classdef Coherent_detector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Image                        matlab.ui.control.Image
        EnvelopeDetectorLabel        matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        CoherentDetectorLabel        matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        aaaaLabel                    matlab.ui.control.Label
        aaaaLabel_2                  matlab.ui.control.Label
        TabGroup3                    matlab.ui.container.TabGroup
        TheoryTab                    matlab.ui.container.Tab
        TabGroup4                    matlab.ui.container.TabGroup
        Page1Tab                     matlab.ui.container.Tab
        TextArea                     matlab.ui.control.TextArea
        Image8                       matlab.ui.control.Image
        Page2Tab                     matlab.ui.container.Tab
        Image9                       matlab.ui.control.Image
        Image10                      matlab.ui.control.Image
        SimulationTab                matlab.ui.container.Tab
        mtmessagesignalButton        matlab.ui.control.Button
        CtCarrierSignalButton        matlab.ui.control.Button
        mtxctDSBSCSignalButton       matlab.ui.control.Button
        XLabel                       matlab.ui.control.Label
        XLabel_2                     matlab.ui.control.Label
        VCOButton                    matlab.ui.control.Button
        LPFButton                    matlab.ui.control.Button
        stDemodulatedSignalButton    matlab.ui.control.Button
        mtxctxVCOButton              matlab.ui.control.Button
        Image3                       matlab.ui.control.Image
        Image3_2                     matlab.ui.control.Image
        Image3_3                     matlab.ui.control.Image
        Image3_4                     matlab.ui.control.Image
        Image3_5                     matlab.ui.control.Image
        Image3_6                     matlab.ui.control.Image
        Image7                       matlab.ui.control.Image
        Image7_2                     matlab.ui.control.Image
        TabGroup2                    matlab.ui.container.TabGroup
        MessageTab                   matlab.ui.container.Tab
        TabGroup                     matlab.ui.container.TabGroup
        TimeDomainTab                matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        FourierDomainTab             matlab.ui.container.Tab
        UIAxes_2                     matlab.ui.control.UIAxes
        AmplitudeVSliderLabel        matlab.ui.control.Label
        AmplitudeVSlider             matlab.ui.control.Slider
        FrequencyHzEditField_2Label  matlab.ui.control.Label
        FrequencyHzEditField_2       matlab.ui.control.NumericEditField
        CarrierTab                   matlab.ui.container.Tab
        TabGroup_2                   matlab.ui.container.TabGroup
        TimeDomainTab_2              matlab.ui.container.Tab
        UIAxes2                      matlab.ui.control.UIAxes
        FourierDomainTab_2           matlab.ui.container.Tab
        UIAxes2_2                    matlab.ui.control.UIAxes
        AmplitudeVSlider_2Label      matlab.ui.control.Label
        AmplitudeVSlider_2           matlab.ui.control.Slider
        FrequencyHzEditFieldLabel    matlab.ui.control.Label
        FrequencyHzEditField         matlab.ui.control.NumericEditField
        ModulatingSignalTab          matlab.ui.container.Tab
        TabGroup_3                   matlab.ui.container.TabGroup
        TimeDomainTab_3              matlab.ui.container.Tab
        UIAxes3                      matlab.ui.control.UIAxes
        FourierDomainTab_3           matlab.ui.container.Tab
        UIAxes3_2                    matlab.ui.control.UIAxes
        VCOTab                       matlab.ui.container.Tab
        TabGroup_4                   matlab.ui.container.TabGroup
        TimeDomainTab_4              matlab.ui.container.Tab
        UIAxes2_3                    matlab.ui.control.UIAxes
        FourierDomainTab_4           matlab.ui.container.Tab
        UIAxes_3                     matlab.ui.control.UIAxes
        mtxctxVCOTab                 matlab.ui.container.Tab
        TabGroup_5                   matlab.ui.container.TabGroup
        TimeDomainTab_5              matlab.ui.container.Tab
        UIAxes_4                     matlab.ui.control.UIAxes
        FourierDomainTab_5           matlab.ui.container.Tab
        UIAxes_5                     matlab.ui.control.UIAxes
        LPFTab                       matlab.ui.container.Tab
        UIAxes_6                     matlab.ui.control.UIAxes
        DemodulatedSignalTab         matlab.ui.container.Tab
        TabGroup_6                   matlab.ui.container.TabGroup
        TimeDomainTab_6              matlab.ui.container.Tab
        UIAxes3_3                    matlab.ui.control.UIAxes
        FourierDomainTab_6           matlab.ui.container.Tab
        UIAxes3_4                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: mtmessagesignalButton
        function call_msg(app, event)
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzEditField_2.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            plot(app.UIAxes,t,mt);
            N=length(mt);
            M=fftshift(fft(mt,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes_2,f,abs(M/fs));
            app.mtmessagesignalButton.BackgroundColor=[0.8706 0.7647 0.7647];
        end

        % Button pushed function: CtCarrierSignalButton
        function call_ct(app, event)
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            fs=1000;
            ct=v_c*cos(2*pi*f_c*t);
            plot(app.UIAxes2,t,ct);
             N=length(ct);
            M=fftshift(fft(ct,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes2_2,f,abs(M/fs));
            app.CtCarrierSignalButton.BackgroundColor =[0.702 0.7886 0.9098];
        end

        % Button pushed function: mtxctDSBSCSignalButton
        function call_dsb(app, event)
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzEditField_2.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
             v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            ct=v_c*cos(2*pi*f_c*t);
            dsb=mt.*ct;
            plot(app.UIAxes3,t,dsb);
            N=length(dsb);
            M=fftshift(fft(dsb,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes3_2,f,abs(M/fs));
            app.mtxctDSBSCSignalButton.BackgroundColor=[0.7686 0.9098 0.702];
        end

        % Button pushed function: VCOButton
        function call_vco(app, event)
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            fs=1000;
            plot(app.UIAxes2_3,t,cos(2*pi*f_c*t));
            N=length(cos(2*pi*f_c*t));
            M=fftshift(fft(cos(2*pi*f_c*t),N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes_3,f,abs(M/fs));
            app.VCOButton.BackgroundColor=[0.8902 0.7843 0.8353];
        end

        % Button pushed function: mtxctxVCOButton
        function call_resultant(app, event)
            %dsb
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzEditField_2.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            ct=v_c*cos(2*pi*f_c*t);
            dsb=mt.*ct;
            %plot
            res=dsb.*cos(2*pi*f_c*t);
            plot(app.UIAxes_4,t,res);
            N=length(res);
            M=fftshift(fft(res,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes_5,f,abs(M/fs));
            app.mtxctxVCOButton.BackgroundColor=[0.8706 0.7647 0.7647];
        end

        % Button pushed function: LPFButton
        function call_lpf(app, event)
            f_m=app.FrequencyHzEditField_2.Value;
            ft=-500:500;
            c1=[zeros(1,500-f_m) ones(1,2*f_m+1) zeros(1,500-f_m)];
            plot(app.UIAxes_6, ft,c1);
            app.LPFButton.BackgroundColor= [0.9216 0.8353 0.8353];
        end

        % Button pushed function: stDemodulatedSignalButton
        function call_st(app, event)
            %dsb
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzEditField_2.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            ct=v_c*cos(2*pi*f_c*t);
            dsb=mt.*ct;
            res=dsb.*cos(2*pi*f_c*t);
            N=length(res);
            M=fftshift(fft(res,N));
            f=fs*(-N/2:N/2-1)/N;
            %lpf
            c1=[zeros(1,500-f_m) ones(1,2*f_m+1) zeros(1,500-f_m)];
            out=abs(M/fs).*c1;
            %output
            plot(app.UIAxes3_4,f,out);
            Md=ifft(ifftshift(out));
            plot(app.UIAxes3_3,t,Md*fs);
            app.stDemodulatedSignalButton.BackgroundColor=[0.6941 0.7176 0.7804];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'DTU,_Delhi_official_logo.png';
            app.EnvelopeDetectorLabel = uilabel(app.UIFigure);
            app.EnvelopeDetectorLabel.Text = 'Envelope Detector';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'DTU,_Delhi_official_logo.png';
            app.CoherentDetectorLabel = uilabel(app.UIFigure);
            app.CoherentDetectorLabel.Text = 'Coherent Detector';
            app.Label = uilabel(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.aaaaLabel = uilabel(app.UIFigure);
            app.aaaaLabel.Text = {'a'; 'a'; 'a'; 'a'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; ''; '.'; ''; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '..'; '.'; '.'; ''; '..'; ''; '.'; '.'; ''; '.'; '.'; '..'; ''; '.'; '..'; ''; '.'; ''; ''; ''; ''; ''; ''};
            app.aaaaLabel_2 = uilabel(app.UIFigure);
            app.aaaaLabel_2.Text = {'a'; 'a'; 'a'; 'a'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; ''; '.'; ''; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '..'; '.'; '.'; ''; '..'; ''; '.'; '.'; ''; '.'; '.'; '..'; ''; '.'; '..'; ''; '.'; ''; ''; ''; ''; ''; ''};
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.TheoryTab = uitab(app.TabGroup3);
            app.TheoryTab.Title = 'Theory';
            app.TabGroup4 = uitabgroup(app.TheoryTab);
            app.Page1Tab = uitab(app.TabGroup4);
            app.Page1Tab.Title = 'Page 1';
            app.TextArea = uitextarea(app.Page1Tab);
            app.TextArea.Value = {'Coherent detection originates from radio communications, where a local carrier mixes with the received radio frequency (RF) signal to generate a product term. As a result, the received RF signal can be frequency translated and demodulated.'};
            app.Image8 = uiimage(app.Page1Tab);
            app.Image8.ImageSource = 'coherent1.png';
            app.Page2Tab = uitab(app.TabGroup4);
            app.Page2Tab.Title = 'Page 2';
            app.Image9 = uiimage(app.Page2Tab);
            app.Image9.ImageSource = 'coherent2.png';
            app.Image10 = uiimage(app.Page2Tab);
            app.Image10.ImageSource = 'coherent-detection-of-DSB-SC-modulated-wave.png';
            app.SimulationTab = uitab(app.TabGroup3);
            app.SimulationTab.Title = 'Simulation';
            app.mtmessagesignalButton = uibutton(app.SimulationTab, 'push');
            app.mtmessagesignalButton.ButtonPushedFcn = createCallbackFcn(app, @call_msg, true);
            app.mtmessagesignalButton.Text = {'m(t)'; 'message signal'};
            app.CtCarrierSignalButton = uibutton(app.SimulationTab, 'push');
            app.CtCarrierSignalButton.ButtonPushedFcn = createCallbackFcn(app, @call_ct, true);
            app.CtCarrierSignalButton.Text = {'C(t)'; 'Carrier Signal '};
            app.mtxctDSBSCSignalButton = uibutton(app.SimulationTab, 'push');
            app.mtxctDSBSCSignalButton.ButtonPushedFcn = createCallbackFcn(app, @call_dsb, true);
            app.mtxctDSBSCSignalButton.Text = {'m(t) x c(t)'; 'DSB-SC Signal'; ''};
            app.XLabel = uilabel(app.SimulationTab);
            app.XLabel.Text = 'X';
            app.XLabel_2 = uilabel(app.SimulationTab);
            app.XLabel_2.Text = 'X';
            app.VCOButton = uibutton(app.SimulationTab, 'push');
            app.VCOButton.ButtonPushedFcn = createCallbackFcn(app, @call_vco, true);
            app.VCOButton.Text = {'VCO'; ''};
            app.LPFButton = uibutton(app.SimulationTab, 'push');
            app.LPFButton.ButtonPushedFcn = createCallbackFcn(app, @call_lpf, true);
            app.LPFButton.Text = 'LPF';
            app.stDemodulatedSignalButton = uibutton(app.SimulationTab, 'push');
            app.stDemodulatedSignalButton.ButtonPushedFcn = createCallbackFcn(app, @call_st, true);
            app.stDemodulatedSignalButton.Text = {'s(t)'; 'Demodulated '; 'Signal'};
            app.mtxctxVCOButton = uibutton(app.SimulationTab, 'push');
            app.mtxctxVCOButton.ButtonPushedFcn = createCallbackFcn(app, @call_resultant, true);
            app.mtxctxVCOButton.Text = 'm(t) x c(t) x VCO';
            app.Image3 = uiimage(app.SimulationTab);
            app.Image3.ImageSource = 'arrow.png';
            app.Image3_2 = uiimage(app.SimulationTab);
            app.Image3_2.ImageSource = 'arrow.png';
            app.Image3_3 = uiimage(app.SimulationTab);
            app.Image3_3.ImageSource = 'arrow.png';
            app.Image3_4 = uiimage(app.SimulationTab);
            app.Image3_4.ImageSource = 'arrow.png';
            app.Image3_5 = uiimage(app.SimulationTab);
            app.Image3_5.ImageSource = 'arrow.png';
            app.Image3_6 = uiimage(app.SimulationTab);
            app.Image3_6.ImageSource = 'arrow.png';
            app.Image7 = uiimage(app.SimulationTab);
            app.Image7.ImageSource = 'arrow1.png';
            app.Image7_2 = uiimage(app.SimulationTab);
            app.Image7_2.ImageSource = 'arrow1.png';
            app.TabGroup2 = uitabgroup(app.SimulationTab);
            app.MessageTab = uitab(app.TabGroup2);
            app.MessageTab.Title = 'Message';
            app.TabGroup = uitabgroup(app.MessageTab);
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time Domain';
            app.UIAxes = uiaxes(app.TimeDomainTab);
            title(app.UIAxes, 'Message Signal')
            xlabel(app.UIAxes, 'time-->')
            ylabel(app.UIAxes, 'Amplitude--->')
            app.FourierDomainTab = uitab(app.TabGroup);
            app.FourierDomainTab.Title = 'Fourier Domain';
            app.UIAxes_2 = uiaxes(app.FourierDomainTab);
            title(app.UIAxes_2, 'Message Signal')
            xlabel(app.UIAxes_2, 'Frequency--->')
            ylabel(app.UIAxes_2, 'DFT Values--->')
            app.AmplitudeVSliderLabel = uilabel(app.MessageTab);
            app.AmplitudeVSliderLabel.Text = 'Amplitude (V)';
            app.AmplitudeVSlider = uislider(app.MessageTab);
            app.AmplitudeVSlider.Limits = [0 2];
            app.FrequencyHzEditField_2Label = uilabel(app.MessageTab);
            app.FrequencyHzEditField_2Label.Text = 'Frequency(Hz)';
            app.FrequencyHzEditField_2 = uieditfield(app.MessageTab, 'numeric');
            app.CarrierTab = uitab(app.TabGroup2);
            app.CarrierTab.Title = 'Carrier';
            app.TabGroup_2 = uitabgroup(app.CarrierTab);
            app.TimeDomainTab_2 = uitab(app.TabGroup_2);
            app.TimeDomainTab_2.Title = 'Time Domain';
            app.UIAxes2 = uiaxes(app.TimeDomainTab_2);
            title(app.UIAxes2, 'Carrier Signal')
            xlabel(app.UIAxes2, 'time--->')
            ylabel(app.UIAxes2, 'Amplitude-->')
            app.FourierDomainTab_2 = uitab(app.TabGroup_2);
            app.FourierDomainTab_2.Title = 'Fourier Domain';
            app.UIAxes2_2 = uiaxes(app.FourierDomainTab_2);
            title(app.UIAxes2_2, 'Carrier Signal')
            xlabel(app.UIAxes2_2, 'frequency--->')
            ylabel(app.UIAxes2_2, 'DFT Values-->')
            app.AmplitudeVSlider_2Label = uilabel(app.CarrierTab);
            app.AmplitudeVSlider_2Label.Text = 'Amplitude (V)';
            app.AmplitudeVSlider_2 = uislider(app.CarrierTab);
            app.AmplitudeVSlider_2.Limits = [0 4];
            app.FrequencyHzEditFieldLabel = uilabel(app.CarrierTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency(Hz)';
            app.FrequencyHzEditField = uieditfield(app.CarrierTab, 'numeric');
            app.ModulatingSignalTab = uitab(app.TabGroup2);
            app.ModulatingSignalTab.Title = 'Modulating Signal';
            app.TabGroup_3 = uitabgroup(app.ModulatingSignalTab);
            app.TimeDomainTab_3 = uitab(app.TabGroup_3);
            app.TimeDomainTab_3.Title = 'Time Domain';
            app.UIAxes3 = uiaxes(app.TimeDomainTab_3);
            title(app.UIAxes3, 'DSB-SC Signal')
            xlabel(app.UIAxes3, 'time--->')
            ylabel(app.UIAxes3, 'Amplitude--->')
            app.FourierDomainTab_3 = uitab(app.TabGroup_3);
            app.FourierDomainTab_3.Title = 'Fourier Domain';
            app.UIAxes3_2 = uiaxes(app.FourierDomainTab_3);
            title(app.UIAxes3_2, 'DSB-SC Signal')
            xlabel(app.UIAxes3_2, 'Frequency--->')
            ylabel(app.UIAxes3_2, 'DFT Values--->')
            app.VCOTab = uitab(app.TabGroup2);
            app.VCOTab.Title = 'VCO';
            app.TabGroup_4 = uitabgroup(app.VCOTab);
            app.TimeDomainTab_4 = uitab(app.TabGroup_4);
            app.TimeDomainTab_4.Title = 'Time Domain';
            app.UIAxes2_3 = uiaxes(app.TimeDomainTab_4);
            title(app.UIAxes2_3, 'VCO')
            xlabel(app.UIAxes2_3, 'Time--->')
            ylabel(app.UIAxes2_3, 'Amplitude-->')
            app.FourierDomainTab_4 = uitab(app.TabGroup_4);
            app.FourierDomainTab_4.Title = 'Fourier Domain';
            app.UIAxes_3 = uiaxes(app.FourierDomainTab_4);
            title(app.UIAxes_3, 'VCO')
            xlabel(app.UIAxes_3, 'Frequency--->')
            ylabel(app.UIAxes_3, 'DFT Values--->')
            app.mtxctxVCOTab = uitab(app.TabGroup2);
            app.mtxctxVCOTab.Title = 'm(t)xc(t)xVCO';
            app.TabGroup_5 = uitabgroup(app.mtxctxVCOTab);
            app.TimeDomainTab_5 = uitab(app.TabGroup_5);
            app.TimeDomainTab_5.Title = 'Time Domain';
            app.UIAxes_4 = uiaxes(app.TimeDomainTab_5);
            title(app.UIAxes_4, 'm(t) X c(t) X VCO')
            xlabel(app.UIAxes_4, 'time-->')
            ylabel(app.UIAxes_4, 'Amplitude--->')
            app.FourierDomainTab_5 = uitab(app.TabGroup_5);
            app.FourierDomainTab_5.Title = 'Fourier Domain';
            app.UIAxes_5 = uiaxes(app.FourierDomainTab_5);
            title(app.UIAxes_5, 'm(t) X c(t) X VCO')
            xlabel(app.UIAxes_5, 'Frequency--->')
            ylabel(app.UIAxes_5, 'DFT Values--->')
            app.LPFTab = uitab(app.TabGroup2);
            app.LPFTab.Title = 'LPF';
            app.UIAxes_6 = uiaxes(app.LPFTab);
            title(app.UIAxes_6, 'Frequency Domain')
            xlabel(app.UIAxes_6, 'Frequency--->')
            ylabel(app.UIAxes_6, 'DFT Values--->')
            app.DemodulatedSignalTab = uitab(app.TabGroup2);
            app.DemodulatedSignalTab.Title = 'Demodulated Signal';
            app.TabGroup_6 = uitabgroup(app.DemodulatedSignalTab);
            app.TimeDomainTab_6 = uitab(app.TabGroup_6);
            app.TimeDomainTab_6.Title = 'Time Domain';
            app.UIAxes3_3 = uiaxes(app.TimeDomainTab_6);
            title(app.UIAxes3_3, 'Output Signal')
            xlabel(app.UIAxes3_3, 'time--->')
            ylabel(app.UIAxes3_3, 'Amplitude--->')
            app.FourierDomainTab_6 = uitab(app.TabGroup_6);
            app.FourierDomainTab_6.Title = 'Fourier Domain';
            app.UIAxes3_4 = uiaxes(app.FourierDomainTab_6);
            title(app.UIAxes3_4, 'Output Signal')
            xlabel(app.UIAxes3_4, 'Frequency--->')
            ylabel(app.UIAxes3_4, 'DFT Values--->')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Coherent_detector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end