classdef Envelope_detector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        EnvelopeDetectorLabel     matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        aaaaLabel                 matlab.ui.control.Label
        aaaaLabel_2               matlab.ui.control.Label
        TabGroup8                 matlab.ui.container.TabGroup
        TheoryTab                 matlab.ui.container.Tab
        Image6                    matlab.ui.control.Image
        Image7                    matlab.ui.control.Image
        Image8                    matlab.ui.control.Image
        SimulationTab             matlab.ui.container.Tab
        TabGroup                  matlab.ui.container.TabGroup
        MessagesignalTab          matlab.ui.container.Tab
        AmplitudeVSliderLabel     matlab.ui.control.Label
        AmplitudeVSlider          matlab.ui.control.Slider
        FrequencyHzSliderLabel    matlab.ui.control.Label
        FrequencyHzSlider         matlab.ui.control.Slider
        TabGroup2                 matlab.ui.container.TabGroup
        TimeDomainTab             matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        FrequencyDomainTab        matlab.ui.container.Tab
        UIAxes_2                  matlab.ui.control.UIAxes
        CarriersignalTab          matlab.ui.container.Tab
        TabGroup2_2               matlab.ui.container.TabGroup
        TimeDomainTab_2           matlab.ui.container.Tab
        UIAxes2                   matlab.ui.control.UIAxes
        FrequencyDomainTab_2      matlab.ui.container.Tab
        UIAxes2_2                 matlab.ui.control.UIAxes
        AmplitudeVSlider_2Label   matlab.ui.control.Label
        AmplitudeVSlider_2        matlab.ui.control.Slider
        FrequencyHzSlider_2Label  matlab.ui.control.Label
        FrequencyHzSlider_2       matlab.ui.control.Slider
        ModulatingSignalTab       matlab.ui.container.Tab
        TabGroup2_3               matlab.ui.container.TabGroup
        TimeDomainTab_3           matlab.ui.container.Tab
        UIAxes3                   matlab.ui.control.UIAxes
        FrequencyDomainTab_3      matlab.ui.container.Tab
        UIAxes3_2                 matlab.ui.control.UIAxes
        EnvelopeTab               matlab.ui.container.Tab
        TabGroup2_4               matlab.ui.container.TabGroup
        TimeDomainTab_4           matlab.ui.container.Tab
        UIAxes3_3                 matlab.ui.control.UIAxes
        MessageSignalButton       matlab.ui.control.Button
        CarrierSignalButton       matlab.ui.control.Button
        AMWaveButton              matlab.ui.control.Button
        Label_3                   matlab.ui.control.Label
        Image4                    matlab.ui.control.Image
        Image4_2                  matlab.ui.control.Image
        Image4_3                  matlab.ui.control.Image
        Image4_4                  matlab.ui.control.Image
        XLabel                    matlab.ui.control.Label
        KaEditFieldLabel          matlab.ui.control.Label
        KaEditField               matlab.ui.control.NumericEditField
        XLabel_2                  matlab.ui.control.Label
        EnvelopeButton            matlab.ui.control.Button
        Image5                    matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MessageSignalButton
        function callMsg(app, event)
            v_m=app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            plot(app.UIAxes,t,mt);
            N=length(mt);
            M=fftshift(fft(mt,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes_2,f,abs(M/fs));
            app.MessageSignalButton.BackgroundColor=[0.9686 0.8549 0.8549];
            
        end

        % Button pushed function: CarrierSignalButton
        function call_carr(app, event)
            v_c=app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzSlider_2.Value;
            t=0:1/1000:1;
            fs=1000;
            ct=v_c*cos(2*pi*f_c*t);
            plot(app.UIAxes2,t,ct);
             N=length(ct);
            M=fftshift(fft(ct,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes2_2,f,abs(M/fs));
            app.CarrierSignalButton.BackgroundColor=[0.7294 0.7216 0.9608];
        end

        % Button pushed function: AMWaveButton
        function AMWaveButtonPushed(app, event)
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            mt=v_m*cos(2*pi*f_m*t);
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzSlider_2.Value;
            fs=1000;
            t=0:1/1000:1;
            ct=v_c*cos(2*pi*f_c*t);
            ka=app.KaEditField.Value;
            am_mod=(1+ka*mt).*ct;
            plot(app.UIAxes3,t,am_mod);
            N=length(am_mod);
            M=fftshift(fft(am_mod,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes3_2,f,abs(M/fs));
            app.AMWaveButton.BackgroundColor=[0.8196 0.9216 0.7725];
        end

        % Value changed function: KaEditField
        function KaEditFieldValueChanged(app, event)

             app.KaEditField.BackgroundColor="cyan";
        end

        % Button pushed function: EnvelopeButton
        function EnvelopeButtonPushed(app, event)
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            mt=v_m*cos(2*pi*f_m*t);
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzSlider_2.Value;
            fs=1000;
            t=0:1/1000:1;
            ct=v_c*cos(2*pi*f_c*t);
            ka=app.KaEditField.Value;
            am_mod=(1+ka*mt).*ct;
            plot(app.UIAxes3_3,t,envelope(am_mod));
            app.EnvelopeButton.BackgroundColor=[0.7725 0.9216 0.8667];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'DTU,_Delhi_official_logo.png';
            app.EnvelopeDetectorLabel = uilabel(app.UIFigure);
            app.EnvelopeDetectorLabel.Text = 'Envelope Detector';
            app.Label = uilabel(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.aaaaLabel = uilabel(app.UIFigure);
            app.aaaaLabel.Text = {'a'; 'a'; 'a'; 'a'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; ''; '.'; ''; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '..'; '.'; '.'; ''; '..'; ''; '.'; '.'; ''; '.'; '.'; '..'; ''; '.'; '..'; ''; '.'; ''; ''; ''; ''; ''; ''};
            app.aaaaLabel_2 = uilabel(app.UIFigure);
            app.aaaaLabel_2.Text = {'a'; 'a'; 'a'; 'a'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; ''; '.'; ''; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '..'; '.'; '.'; ''; '..'; ''; '.'; '.'; ''; '.'; '.'; '..'; ''; '.'; '..'; ''; '.'; ''; ''; ''; ''; ''; ''};
            app.TabGroup8 = uitabgroup(app.UIFigure);
            app.TheoryTab = uitab(app.TabGroup8);
            app.TheoryTab.Title = 'Theory';
            app.Image6 = uiimage(app.TheoryTab);
            app.Image6.ImageSource = 'env1.png';
            app.Image7 = uiimage(app.TheoryTab);
            app.Image7.ImageSource = 'env2.png';
            app.Image8 = uiimage(app.TheoryTab);
            app.Image8.ImageSource = 'env3.png';
            app.SimulationTab = uitab(app.TabGroup8);
            app.SimulationTab.Title = 'Simulation';
            app.TabGroup = uitabgroup(app.SimulationTab);
            app.MessagesignalTab = uitab(app.TabGroup);
            app.MessagesignalTab.Title = 'Message signal';
            app.AmplitudeVSliderLabel = uilabel(app.MessagesignalTab);
            app.AmplitudeVSliderLabel.Text = 'Amplitude (V)';
            app.AmplitudeVSlider = uislider(app.MessagesignalTab);
            app.AmplitudeVSlider.Limits = [0 2];
            app.FrequencyHzSliderLabel = uilabel(app.MessagesignalTab);
            app.FrequencyHzSliderLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider = uislider(app.MessagesignalTab);
            app.FrequencyHzSlider.Limits = [0 5];
            app.TabGroup2 = uitabgroup(app.MessagesignalTab);
            app.TimeDomainTab = uitab(app.TabGroup2);
            app.TimeDomainTab.Title = 'Time Domain';
            app.UIAxes = uiaxes(app.TimeDomainTab);
            title(app.UIAxes, 'Message signal')
            xlabel(app.UIAxes, 'Time--->')
            ylabel(app.UIAxes, 'Amplitude-->')
            app.FrequencyDomainTab = uitab(app.TabGroup2);
            app.FrequencyDomainTab.Title = 'Frequency Domain';
            app.UIAxes_2 = uiaxes(app.FrequencyDomainTab);
            title(app.UIAxes_2, 'Message signal')
            xlabel(app.UIAxes_2, 'Frequency--->')
            ylabel(app.UIAxes_2, 'DFT Values--->')
            app.CarriersignalTab = uitab(app.TabGroup);
            app.CarriersignalTab.Title = 'Carrier signal';
            app.TabGroup2_2 = uitabgroup(app.CarriersignalTab);
            app.TimeDomainTab_2 = uitab(app.TabGroup2_2);
            app.TimeDomainTab_2.Title = 'Time Domain';
            app.UIAxes2 = uiaxes(app.TimeDomainTab_2);
            title(app.UIAxes2, 'Carrier Signal')
            xlabel(app.UIAxes2, 'Time--->')
            ylabel(app.UIAxes2, 'Amplitude-->')
            app.FrequencyDomainTab_2 = uitab(app.TabGroup2_2);
            app.FrequencyDomainTab_2.Title = 'Frequency Domain';
            app.UIAxes2_2 = uiaxes(app.FrequencyDomainTab_2);
            title(app.UIAxes2_2, 'Carrier Signal')
            xlabel(app.UIAxes2_2, 'Frequency--->')
            ylabel(app.UIAxes2_2, 'DFT Values-->')
            app.AmplitudeVSlider_2Label = uilabel(app.CarriersignalTab);
            app.AmplitudeVSlider_2Label.Text = 'Amplitude (V)';
            app.AmplitudeVSlider_2 = uislider(app.CarriersignalTab);
            app.AmplitudeVSlider_2.Limits = [0 4];
            app.FrequencyHzSlider_2Label = uilabel(app.CarriersignalTab);
            app.FrequencyHzSlider_2Label.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider_2 = uislider(app.CarriersignalTab);
            app.FrequencyHzSlider_2.Limits = [0 60];
            app.ModulatingSignalTab = uitab(app.TabGroup);
            app.ModulatingSignalTab.Title = 'Modulating Signal';
            app.TabGroup2_3 = uitabgroup(app.ModulatingSignalTab);
            app.TimeDomainTab_3 = uitab(app.TabGroup2_3);
            app.TimeDomainTab_3.Title = 'Time Domain';
            app.UIAxes3 = uiaxes(app.TimeDomainTab_3);
            title(app.UIAxes3, 'AM Signal')
            xlabel(app.UIAxes3, 'Time--->')
            ylabel(app.UIAxes3, 'Amplitude-->')
            app.FrequencyDomainTab_3 = uitab(app.TabGroup2_3);
            app.FrequencyDomainTab_3.Title = 'Frequency Domain';
            app.UIAxes3_2 = uiaxes(app.FrequencyDomainTab_3);
            title(app.UIAxes3_2, 'AM Signal')
            xlabel(app.UIAxes3_2, 'Frequency--->')
            ylabel(app.UIAxes3_2, 'DFT Values-->')
            app.EnvelopeTab = uitab(app.TabGroup);
            app.EnvelopeTab.Title = 'Envelope';
            app.TabGroup2_4 = uitabgroup(app.EnvelopeTab);
            app.TimeDomainTab_4 = uitab(app.TabGroup2_4);
            app.TimeDomainTab_4.Title = 'Time Domain';
            app.UIAxes3_3 = uiaxes(app.TimeDomainTab_4);
            title(app.UIAxes3_3, {'Envelope'; ''})
            xlabel(app.UIAxes3_3, 'Time--->')
            ylabel(app.UIAxes3_3, 'Amplitude-->')
            app.MessageSignalButton = uibutton(app.SimulationTab, 'push');
            app.MessageSignalButton.ButtonPushedFcn = createCallbackFcn(app, @callMsg, true);
            app.MessageSignalButton.Text = {'Message '; 'Signal'};
            app.CarrierSignalButton = uibutton(app.SimulationTab, 'push');
            app.CarrierSignalButton.ButtonPushedFcn = createCallbackFcn(app, @call_carr, true);
            app.CarrierSignalButton.Text = {'Carrier '; 'Signal'; ''};
            app.AMWaveButton = uibutton(app.SimulationTab, 'push');
            app.AMWaveButton.ButtonPushedFcn = createCallbackFcn(app, @AMWaveButtonPushed, true);
            app.AMWaveButton.Text = 'AM Wave';
            app.Label_3 = uilabel(app.SimulationTab);
            app.Label_3.Text = {'+'; ''; '1'};
            app.Image4 = uiimage(app.SimulationTab);
            app.Image4.ImageSource = 'arrow.png';
            app.Image4_2 = uiimage(app.SimulationTab);
            app.Image4_2.ImageSource = 'arrow.png';
            app.Image4_3 = uiimage(app.SimulationTab);
            app.Image4_3.ImageSource = 'arrow.png';
            app.Image4_4 = uiimage(app.SimulationTab);
            app.Image4_4.ImageSource = 'arrow.png';
            app.XLabel = uilabel(app.SimulationTab);
            app.XLabel.Text = 'X';
            app.KaEditFieldLabel = uilabel(app.SimulationTab);
            app.KaEditFieldLabel.Text = 'Ka';
            app.KaEditField = uieditfield(app.SimulationTab, 'numeric');
            app.KaEditField.ValueChangedFcn = createCallbackFcn(app, @KaEditFieldValueChanged, true);
            app.XLabel_2 = uilabel(app.SimulationTab);
            app.XLabel_2.Text = 'X';
            app.EnvelopeButton = uibutton(app.SimulationTab, 'push');
            app.EnvelopeButton.ButtonPushedFcn = createCallbackFcn(app, @EnvelopeButtonPushed, true);
            app.EnvelopeButton.Text = 'Envelope';
            app.Image5 = uiimage(app.SimulationTab);
            app.Image5.ImageSource = 'WhatsApp Image 2021-05-17 at 7.16.47 PM.jpeg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Envelope_detector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end