classdef PhaseShiftingMethod < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image                      matlab.ui.control.Image
        PhaseShiftingMethodLabel   matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        MessageSignalTab           matlab.ui.container.Tab
        TabGroup2                  matlab.ui.container.TabGroup
        TimeDomainTab              matlab.ui.container.Tab
        UIAxes                     matlab.ui.control.UIAxes
        FrequencyDomainTab         matlab.ui.container.Tab
        UIAxes2                    matlab.ui.control.UIAxes
        AmplitudeVSliderLabel      matlab.ui.control.Label
        AmplitudeVSlider           matlab.ui.control.Slider
        FrequencyHzSliderLabel     matlab.ui.control.Label
        FrequencyHzSlider          matlab.ui.control.Slider
        CarrierSignalTab           matlab.ui.container.Tab
        TabGroup2_2                matlab.ui.container.TabGroup
        TimeDomainTab_2            matlab.ui.container.Tab
        UIAxes3                    matlab.ui.control.UIAxes
        FrequencyDomainTab_2       matlab.ui.container.Tab
        UIAxes2_2                  matlab.ui.control.UIAxes
        AmplitudeVSlider_2Label    matlab.ui.control.Label
        AmplitudeVSlider_2         matlab.ui.control.Slider
        FrequencyHzEditFieldLabel  matlab.ui.control.Label
        FrequencyHzEditField       matlab.ui.control.NumericEditField
        HilbertSignalTab           matlab.ui.container.Tab
        TabGroup4_3                matlab.ui.container.TabGroup
        TimedomainTab_3            matlab.ui.container.Tab
        UIAxes6_3                  matlab.ui.control.UIAxes
        FrequnecyDomainTab_3       matlab.ui.container.Tab
        UIAxes7_3                  matlab.ui.control.UIAxes
        LSBSignalTab               matlab.ui.container.Tab
        TabGroup4                  matlab.ui.container.TabGroup
        TimedomainTab              matlab.ui.container.Tab
        UIAxes6                    matlab.ui.control.UIAxes
        FrequnecyDomainTab         matlab.ui.container.Tab
        UIAxes7                    matlab.ui.control.UIAxes
        USBSignalTab               matlab.ui.container.Tab
        TabGroup4_2                matlab.ui.container.TabGroup
        TimedomainTab_2            matlab.ui.container.Tab
        UIAxes6_2                  matlab.ui.control.UIAxes
        FrequnecyDomainTab_2       matlab.ui.container.Tab
        UIAxes7_2                  matlab.ui.control.UIAxes
        MessagesignalmtButton      matlab.ui.control.Button
        Image2                     matlab.ui.control.Image
        XLabel                     matlab.ui.control.Label
        CarrierSignalCtButton      matlab.ui.control.Button
        LSBSignalButton            matlab.ui.control.Button
        USBSignalButton            matlab.ui.control.Button
        HilbertTransformButton     matlab.ui.control.Button
        Image6_2                   matlab.ui.control.Image
        Label                      matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Image3                     matlab.ui.control.Image
        Image3_2                   matlab.ui.control.Image
        Image2_2                   matlab.ui.control.Image
        Image2_3                   matlab.ui.control.Image
        XLabel_2                   matlab.ui.control.Label
        degreePhaseshifterLabel    matlab.ui.control.Label
        Image3_3                   matlab.ui.control.Image
        Image2_4                   matlab.ui.control.Image
        Image2_5                   matlab.ui.control.Image
        Image2_6                   matlab.ui.control.Image
        Image7                     matlab.ui.control.Image
        Label_3                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        Image2_7                   matlab.ui.control.Image
        Image3_4                   matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MessagesignalmtButton
        function call_msg(app, event)
             v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            plot(app.UIAxes,t,mt);
            %freq domain
            N=length(mt);
            M=fftshift(fft(mt,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes2,f,abs(M/fs));
            %color change
            app.MessagesignalmtButton.BackgroundColor=[0.7725 0.8902 0.8706];
        end

        % Callback function: CarrierSignalCtButton, CarrierSignalTab
        function call_carrier(app, event)
            %time domain
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            fs=1000;
            ct=v_c*cos(2*pi*f_c*t);
            %freq domain 
            plot(app.UIAxes3,t,ct);
             N=length(ct);
            M=fftshift(fft(ct,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes2_2,f,abs(M/fs));
            %color change
            app.CarrierSignalCtButton.BackgroundColor=[0.9216 0.8078 0.8627];
        end

        % Callback function
        function call_dsb(app, event)
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            ct=v_c*cos(2*pi*f_c*t);
            dsb=mt.*ct;
            plot(app.UIAxes_2,t,dsb);
            %freq domain
            N=length(dsb);
            M=fftshift(fft(dsb,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes2_3,f,abs(M/fs));
            %color
            app.ModulatingSignalButton.BackgroundColor=[0.7725 0.8431 0.9412];
        end

        % Button pushed function: HilbertTransformButton
        function call_hilbert(app, event)
            %Hilbert Message Signal 
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            yt =imag(hilbert(mt)) ;
            plot(app.UIAxes6_3,t,yt);
            %freq domain
            Nyt=length(yt);
            Myt=fftshift(fft(yt,Nyt));
            fyt=fs*(-Nyt/2:Nyt/2-1)/Nyt;
            plot(app.UIAxes7_3,fyt,abs(Myt/fs));
            %color
            app.HilbertTransformButton.BackgroundColor=[0.9294 0.8549 0.8549];
        end

        % Button pushed function: LSBSignalButton
        function call_lsb(app, event)
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            ct=v_c*cos(2*pi*f_c*t);
            dsb=mt.*ct;
            yt = imag(hilbert(mt));
            xt=imag(hilbert(ct));
            %Lower Side BAnd
            lbu = (dsb+yt.*xt);
            plot(app.UIAxes6,t,lbu);
            Nlbu=length(lbu);
            Mlbu=fftshift(fft(lbu,Nlbu));
            flbu=fs*(-Nlbu/2:Nlbu/2-1)/Nlbu;
            plot(app.UIAxes7,flbu,abs(Mlbu/fs));
            app.LSBSignalButton.BackgroundColor=[0.7804 0.9216 0.8863];
        end

        % Button pushed function: USBSignalButton
        function call_usb(app, event)
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            ct=v_c*cos(2*pi*f_c*t);
            dsb=mt.*ct;
            yt = imag(hilbert(mt));
            xt=imag(hilbert(ct));
            %Upper sIDE bAND 
            sbu = dsb-(yt.*xt);
            plot(app.UIAxes6_2,t,sbu);
            Nsbu=length(sbu);
            Msbu=fftshift(fft(sbu,Nsbu));
            fsbu=fs*(-Nsbu/2:Nsbu/2-1)/Nsbu;
            plot(app.UIAxes7_2,fsbu,abs(Msbu/fs));
            app.USBSignalButton.BackgroundColor=[0.9294 0.8549 0.8549];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'DTU,_Delhi_official_logo.png';
            app.PhaseShiftingMethodLabel = uilabel(app.UIFigure);
            app.PhaseShiftingMethodLabel.Text = 'Phase Shifting Method';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MessageSignalTab = uitab(app.TabGroup);
            app.MessageSignalTab.Title = 'Message Signal';
            app.TabGroup2 = uitabgroup(app.MessageSignalTab);
            app.TimeDomainTab = uitab(app.TabGroup2);
            app.TimeDomainTab.Title = 'Time Domain';
            app.UIAxes = uiaxes(app.TimeDomainTab);
            title(app.UIAxes, 'Message Signal')
            xlabel(app.UIAxes, 'Time--->')
            ylabel(app.UIAxes, 'Amplitude--->')
            app.FrequencyDomainTab = uitab(app.TabGroup2);
            app.FrequencyDomainTab.Title = 'Frequency Domain';
            app.UIAxes2 = uiaxes(app.FrequencyDomainTab);
            title(app.UIAxes2, 'Message Signal')
            xlabel(app.UIAxes2, 'Time--->')
            ylabel(app.UIAxes2, 'DFT Values-->')
            app.AmplitudeVSliderLabel = uilabel(app.MessageSignalTab);
            app.AmplitudeVSliderLabel.Text = 'Amplitude (V)';
            app.AmplitudeVSlider = uislider(app.MessageSignalTab);
            app.AmplitudeVSlider.Limits = [0 2];
            app.FrequencyHzSliderLabel = uilabel(app.MessageSignalTab);
            app.FrequencyHzSliderLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider = uislider(app.MessageSignalTab);
            app.FrequencyHzSlider.Limits = [0 5];
            app.CarrierSignalTab = uitab(app.TabGroup);
            app.CarrierSignalTab.SizeChangedFcn = createCallbackFcn(app, @call_carrier, true);
            app.CarrierSignalTab.Title = 'Carrier Signal';
            app.TabGroup2_2 = uitabgroup(app.CarrierSignalTab);
            app.TimeDomainTab_2 = uitab(app.TabGroup2_2);
            app.TimeDomainTab_2.Title = 'Time Domain';
            app.UIAxes3 = uiaxes(app.TimeDomainTab_2);
            title(app.UIAxes3, 'Carrier Signal')
            xlabel(app.UIAxes3, 'Time--->')
            ylabel(app.UIAxes3, 'Amplitude--->')
            app.FrequencyDomainTab_2 = uitab(app.TabGroup2_2);
            app.FrequencyDomainTab_2.Title = 'Frequency Domain';
            app.UIAxes2_2 = uiaxes(app.FrequencyDomainTab_2);
            title(app.UIAxes2_2, 'Carrier Signal')
            xlabel(app.UIAxes2_2, 'Time--->')
            ylabel(app.UIAxes2_2, 'DFT Values-->')
            app.AmplitudeVSlider_2Label = uilabel(app.CarrierSignalTab);
            app.AmplitudeVSlider_2Label.Text = 'Amplitude (V)';
            app.AmplitudeVSlider_2 = uislider(app.CarrierSignalTab);
            app.AmplitudeVSlider_2.Limits = [0 2];
            app.FrequencyHzEditFieldLabel = uilabel(app.CarrierSignalTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency(Hz)';
            app.FrequencyHzEditField = uieditfield(app.CarrierSignalTab, 'numeric');
            app.HilbertSignalTab = uitab(app.TabGroup);
            app.HilbertSignalTab.Title = 'Hilbert Signal';
            app.TabGroup4_3 = uitabgroup(app.HilbertSignalTab);
            app.TimedomainTab_3 = uitab(app.TabGroup4_3);
            app.TimedomainTab_3.Title = 'Time domain';
            app.UIAxes6_3 = uiaxes(app.TimedomainTab_3);
            title(app.UIAxes6_3, 'Hirbert Signal')
            xlabel(app.UIAxes6_3, 'Time--->')
            ylabel(app.UIAxes6_3, 'Amplitude--->')
            app.FrequnecyDomainTab_3 = uitab(app.TabGroup4_3);
            app.FrequnecyDomainTab_3.Title = 'Frequnecy Domain';
            app.UIAxes7_3 = uiaxes(app.FrequnecyDomainTab_3);
            title(app.UIAxes7_3, 'Hilbert Signal')
            xlabel(app.UIAxes7_3, 'time-->')
            ylabel(app.UIAxes7_3, 'DFT Values-->')
            app.LSBSignalTab = uitab(app.TabGroup);
            app.LSBSignalTab.Title = 'LSB Signal';
            app.TabGroup4 = uitabgroup(app.LSBSignalTab);
            app.TimedomainTab = uitab(app.TabGroup4);
            app.TimedomainTab.Title = 'Time domain';
            app.UIAxes6 = uiaxes(app.TimedomainTab);
            title(app.UIAxes6, 'LSB Signal')
            xlabel(app.UIAxes6, 'Time--->')
            ylabel(app.UIAxes6, 'Amplitude--->')
            app.FrequnecyDomainTab = uitab(app.TabGroup4);
            app.FrequnecyDomainTab.Title = 'Frequnecy Domain';
            app.UIAxes7 = uiaxes(app.FrequnecyDomainTab);
            title(app.UIAxes7, 'LSB Signal')
            xlabel(app.UIAxes7, 'time-->')
            ylabel(app.UIAxes7, 'DFT Values-->')
            app.USBSignalTab = uitab(app.TabGroup);
            app.USBSignalTab.Title = 'USB Signal';
            app.TabGroup4_2 = uitabgroup(app.USBSignalTab);
            app.TimedomainTab_2 = uitab(app.TabGroup4_2);
            app.TimedomainTab_2.Title = 'Time domain';
            app.UIAxes6_2 = uiaxes(app.TimedomainTab_2);
            title(app.UIAxes6_2, 'USB Signal')
            xlabel(app.UIAxes6_2, 'Time--->')
            ylabel(app.UIAxes6_2, 'Amplitude--->')
            app.FrequnecyDomainTab_2 = uitab(app.TabGroup4_2);
            app.FrequnecyDomainTab_2.Title = 'Frequnecy Domain';
            app.UIAxes7_2 = uiaxes(app.FrequnecyDomainTab_2);
            title(app.UIAxes7_2, 'USB Signal')
            xlabel(app.UIAxes7_2, 'time-->')
            ylabel(app.UIAxes7_2, 'DFT Values-->')
            app.MessagesignalmtButton = uibutton(app.UIFigure, 'push');
            app.MessagesignalmtButton.ButtonPushedFcn = createCallbackFcn(app, @call_msg, true);
            app.MessagesignalmtButton.Text = {'Message signal'; 'm(t)'};
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'arrow.png';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.CarrierSignalCtButton = uibutton(app.UIFigure, 'push');
            app.CarrierSignalCtButton.ButtonPushedFcn = createCallbackFcn(app, @call_carrier, true);
            app.CarrierSignalCtButton.Text = {'Carrier Signal'; 'C(t)'};
            app.LSBSignalButton = uibutton(app.UIFigure, 'push');
            app.LSBSignalButton.ButtonPushedFcn = createCallbackFcn(app, @call_lsb, true);
            app.LSBSignalButton.Text = {'LSB'; 'Signal'};
            app.USBSignalButton = uibutton(app.UIFigure, 'push');
            app.USBSignalButton.ButtonPushedFcn = createCallbackFcn(app, @call_usb, true);
            app.USBSignalButton.Text = {'USB '; 'Signal'};
            app.HilbertTransformButton = uibutton(app.UIFigure, 'push');
            app.HilbertTransformButton.ButtonPushedFcn = createCallbackFcn(app, @call_hilbert, true);
            app.HilbertTransformButton.Text = {'Hilbert '; 'Transform'};
            app.Image6_2 = uiimage(app.UIFigure);
            app.Image6_2.ImageSource = 'arrow1.png';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '+';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'arrow2.png';
            app.Image3_2 = uiimage(app.UIFigure);
            app.Image3_2.ImageSource = 'arrow2.png';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = 'arrow.png';
            app.Image2_3 = uiimage(app.UIFigure);
            app.Image2_3.ImageSource = 'arrow.png';
            app.XLabel_2 = uilabel(app.UIFigure);
            app.XLabel_2.Text = 'X';
            app.degreePhaseshifterLabel = uilabel(app.UIFigure);
            app.degreePhaseshifterLabel.Text = {'-90 degree'; 'Phase shifter'};
            app.Image3_3 = uiimage(app.UIFigure);
            app.Image3_3.ImageSource = 'arrow2.png';
            app.Image2_4 = uiimage(app.UIFigure);
            app.Image2_4.ImageSource = 'arrow.png';
            app.Image2_5 = uiimage(app.UIFigure);
            app.Image2_5.ImageSource = 'arrow.png';
            app.Image2_6 = uiimage(app.UIFigure);
            app.Image2_6.ImageSource = 'arrow.png';
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = 'arrow3.png';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '----------------------------------';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '------------------------------';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Lamp = uilamp(app.UIFigure);
            app.Image2_7 = uiimage(app.UIFigure);
            app.Image2_7.ImageSource = 'arrow.png';
            app.Image3_4 = uiimage(app.UIFigure);
            app.Image3_4.ImageSource = 'arrow2.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhaseShiftingMethod

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end