classdef QAM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        QAMLabel                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        aaaaLabel                       matlab.ui.control.Label
        aaaaLabel_2                     matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        TheoryTab                       matlab.ui.container.Tab
        Image11                         matlab.ui.control.Image
        Image12                         matlab.ui.control.Image
        Image13                         matlab.ui.control.Image
        Image14                         matlab.ui.control.Image
        Image15                         matlab.ui.control.Image
        SimulationTab                   matlab.ui.container.Tab
        InphaseMessageSignalButton      matlab.ui.control.Button
        QuadratureMessageSignalButton   matlab.ui.control.Button
        CarrierSignalButton             matlab.ui.control.Button
        MultiplexSignalxtButton         matlab.ui.control.Button
        QuadratureDemodulatedSignalButton  matlab.ui.control.Button
        VCOButton                       matlab.ui.control.Button
        LPFButton                       matlab.ui.control.Button
        LPFButton_2                     matlab.ui.control.Button
        InphaseDemodulatedSignalButton  matlab.ui.control.Button
        XLabel                          matlab.ui.control.Label
        degreePhaseshifterLabel         matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        Label_25                        matlab.ui.control.Label
        XLabel_3                        matlab.ui.control.Label
        degreePhaseshifterLabel_2       matlab.ui.control.Label
        XLabel_4                        matlab.ui.control.Label
        Label_26                        matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        Label_28                        matlab.ui.control.Label
        Label_29                        matlab.ui.control.Label
        Label_30                        matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Image2_2                        matlab.ui.control.Image
        Image2_3                        matlab.ui.control.Image
        Image2_4                        matlab.ui.control.Image
        Image2_5                        matlab.ui.control.Image
        Image2_6                        matlab.ui.control.Image
        Image2_7                        matlab.ui.control.Image
        Image2_8                        matlab.ui.control.Image
        Image9                          matlab.ui.control.Image
        Image9_2                        matlab.ui.control.Image
        Image10                         matlab.ui.control.Image
        Image10_2                       matlab.ui.control.Image
        Image10_3                       matlab.ui.control.Image
        Image10_4                       matlab.ui.control.Image
        Label_31                        matlab.ui.control.Label
        Label_32                        matlab.ui.control.Label
        Label_33                        matlab.ui.control.Label
        QAMTransmitterLabel             matlab.ui.control.Label
        Label_34                        matlab.ui.control.Label
        Label_35                        matlab.ui.control.Label
        Label_36                        matlab.ui.control.Label
        QAMReceiverLabel                matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        InphasemtTab                    matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        AmplitudeVSliderLabel           matlab.ui.control.Label
        AmplitudeVSlider                matlab.ui.control.Slider
        FrequencyHzSliderLabel          matlab.ui.control.Label
        FrequencyHzSlider               matlab.ui.control.Slider
        QuadraturemtTab                 matlab.ui.container.Tab
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        AmplitudeVSlider_2Label         matlab.ui.control.Label
        AmplitudeVSlider_2              matlab.ui.control.Slider
        FrequencyHzSlider_2Label        matlab.ui.control.Label
        FrequencyHzSlider_2             matlab.ui.control.Slider
        CarrierSignalTab                matlab.ui.container.Tab
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        AmplitudeVSlider_3Label         matlab.ui.control.Label
        AmplitudeVSlider_3              matlab.ui.control.Slider
        xtTab                           matlab.ui.container.Tab
        UIAxes2_4                       matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        VCOTab                          matlab.ui.container.Tab
        UIAxes2_7                       matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        LPFTab                          matlab.ui.container.Tab
        UIAxes_7                        matlab.ui.control.UIAxes
        InphaseDemodulatedSignalTab     matlab.ui.container.Tab
        UIAxes2_5                       matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        QuadratureDemodulatedSignalTab  matlab.ui.container.Tab
        UIAxes2_6                       matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InphaseMessageSignalButton
        function call_msg(app, event)
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            plot(app.UIAxes2,t,mt);
            %freq domain
            N=length(mt);
            M=fftshift(fft(mt,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes,f,abs(M/fs));
            app.InphaseMessageSignalButton.BackgroundColor=[0.9098 0.8275 0.8275];
        end

        % Button pushed function: QuadratureMessageSignalButton
        function call_q(app, event)
             v_q = app.AmplitudeVSlider_2.Value;
            f_q=app.FrequencyHzSlider_2.Value;
            t=0:1/1000:1;
            fs=1000;
            mq=v_q*cos(2*pi*f_q*t);
            plot(app.UIAxes2_2,t,mq);
            %freq domain
            Nq=length(mq);
            Mq=fftshift(fft(mq,Nq));
            f=fs*(-Nq/2:Nq/2-1)/Nq;
            plot(app.UIAxes_2,f,abs(Mq/fs));
            app.QuadratureMessageSignalButton.BackgroundColor=[0.8549 0.8902 0.8353];
        end

        % Button pushed function: CarrierSignalButton
        function call_vco(app, event)
            v_c=app.AmplitudeVSlider_3.Value;
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            fs=1000;
            ct=v_c*cos(2*pi*f_c*t);
            %freq domain 
            plot(app.UIAxes2_3,t,ct);
            N=length(ct);
            M=fftshift(fft(ct,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes_3,f,abs(M/fs));
            app.CarrierSignalButton.BackgroundColor=[0.7647 0.8588 0.8706];
        end

        % Button pushed function: MultiplexSignalxtButton
        function call_xt(app, event)
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            v_c=app.AmplitudeVSlider_3.Value;
            f_c=app.FrequencyHzEditField.Value;
            v_q = app.AmplitudeVSlider_2.Value;
            f_q=app.FrequencyHzSlider_2.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            ct=v_c*cos(2*pi*f_c*t);
            mq=v_q*cos(2*pi*f_q*t);
            mx=(mt.*ct)-(mq.*(v_c*sin(2*pi*f_c*t)));
            plot(app.UIAxes2_4,t,mx);
            N=length(mx);
            M=fftshift(fft(mx,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes_4,f,abs(M/fs));
            app.MultiplexSignalxtButton.BackgroundColor= [0.7843 0.8471 0.902];
        end

        % Button pushed function: VCOButton
        function call_VCO(app, event)
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            fs=1000;
            plot(app.UIAxes2_7,t,cos(2*pi*f_c*t));
            N=length(cos(2*pi*f_c*t));
            M=fftshift(fft(cos(2*pi*f_c*t),N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes_8,f,abs(M/fs));
            app.VCOButton.BackgroundColor=[0.8902 0.7843 0.8353];
        end

        % Button pushed function: LPFButton, LPFButton_2
        function call_lpf(app, event)

            ft=-500:500;
            c1=[zeros(1,491) ones(1,19) zeros(1,491)];
            plot(app.UIAxes_7, ft,c1);
            app.LPFButton.BackgroundColor= [0.9216 0.8353 0.8353];
            app.LPFButton_2.BackgroundColor= [0.9216 0.8353 0.8353];
        end

        % Button pushed function: InphaseDemodulatedSignalButton
        function call_midt(app, event)
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value; 
            v_c=app.AmplitudeVSlider_3.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            plot(app.UIAxes2_5,t,v_c*mt*0.5);
            N=length(v_c*mt*0.5);
            M=fftshift(fft(v_c*mt*0.5,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes_5,f,abs(M/fs));
            app.InphaseDemodulatedSignalButton.BackgroundColor=[0.8118 0.698 0.698];
        end

        % Button pushed function: QuadratureDemodulatedSignalButton
        function call_mqt(app, event)
            v_q = app.AmplitudeVSlider_2.Value;
            f_q=app.FrequencyHzSlider_2.Value;
            v_c=app.AmplitudeVSlider_3.Value;
            t=0:1/1000:1;
            fs=1000;
            mq=v_q*cos(2*pi*f_q*t);
            plot(app.UIAxes2_6,t,v_c*mq*0.5);
            N=length(v_c*mq*0.5);
            M=fftshift(fft(v_c*mq*0.5,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes_6,f,abs(M/fs));
            app.QuadratureDemodulatedSignalButton.BackgroundColor=[0.7843 0.8588 0.851];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'DTU,_Delhi_official_logo.png';
            app.QAMLabel = uilabel(app.UIFigure);
            app.QAMLabel.Text = 'QAM';
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_19 = uilabel(app.UIFigure);
            app.aaaaLabel = uilabel(app.UIFigure);
            app.aaaaLabel.Text = {'a'; 'a'; 'a'; 'a'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; ''; '.'; ''; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '..'; '.'; '.'; ''; '..'; ''; '.'; '.'; ''; '.'; '.'; '..'; ''; '.'; '..'; ''; '.'; ''; ''; ''; ''; ''; ''};
            app.aaaaLabel_2 = uilabel(app.UIFigure);
            app.aaaaLabel_2.Text = {'a'; 'a'; 'a'; 'a'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; ''; '.'; ''; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '..'; '.'; '.'; ''; '..'; ''; '.'; '.'; ''; '.'; '.'; '..'; ''; '.'; '..'; ''; '.'; ''; ''; ''; ''; ''; ''};
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TheoryTab = uitab(app.TabGroup2);
            app.TheoryTab.Title = 'Theory';
            app.Image11 = uiimage(app.TheoryTab);
            app.Image11.ImageSource = 'qam1.png';
            app.Image12 = uiimage(app.TheoryTab);
            app.Image12.ImageSource = 'qam2.png';
            app.Image13 = uiimage(app.TheoryTab);
            app.Image13.ImageSource = 'qam3.png';
            app.Image14 = uiimage(app.TheoryTab);
            app.Image14.ImageSource = 'qam4.png';
            app.Image15 = uiimage(app.TheoryTab);
            app.Image15.ImageSource = 'qam5.PNG';
            app.SimulationTab = uitab(app.TabGroup2);
            app.SimulationTab.Title = 'Simulation';
            app.InphaseMessageSignalButton = uibutton(app.SimulationTab, 'push');
            app.InphaseMessageSignalButton.ButtonPushedFcn = createCallbackFcn(app, @call_msg, true);
            app.InphaseMessageSignalButton.Text = {'Inphase'; 'Message Signal'};
            app.QuadratureMessageSignalButton = uibutton(app.SimulationTab, 'push');
            app.QuadratureMessageSignalButton.ButtonPushedFcn = createCallbackFcn(app, @call_q, true);
            app.QuadratureMessageSignalButton.Text = {'Quadrature'; 'Message Signal'};
            app.CarrierSignalButton = uibutton(app.SimulationTab, 'push');
            app.CarrierSignalButton.ButtonPushedFcn = createCallbackFcn(app, @call_vco, true);
            app.CarrierSignalButton.Text = {'Carrier Signal'; ''};
            app.MultiplexSignalxtButton = uibutton(app.SimulationTab, 'push');
            app.MultiplexSignalxtButton.ButtonPushedFcn = createCallbackFcn(app, @call_xt, true);
            app.MultiplexSignalxtButton.Text = {'Multiplex Signal'; 'x(t)'; ''; ''};
            app.QuadratureDemodulatedSignalButton = uibutton(app.SimulationTab, 'push');
            app.QuadratureDemodulatedSignalButton.ButtonPushedFcn = createCallbackFcn(app, @call_mqt, true);
            app.QuadratureDemodulatedSignalButton.Text = {'Quadrature'; 'Demodulated Signal'; ''};
            app.VCOButton = uibutton(app.SimulationTab, 'push');
            app.VCOButton.ButtonPushedFcn = createCallbackFcn(app, @call_VCO, true);
            app.VCOButton.Text = {'VCO'; ''};
            app.LPFButton = uibutton(app.SimulationTab, 'push');
            app.LPFButton.ButtonPushedFcn = createCallbackFcn(app, @call_lpf, true);
            app.LPFButton.Text = 'LPF';
            app.LPFButton_2 = uibutton(app.SimulationTab, 'push');
            app.LPFButton_2.ButtonPushedFcn = createCallbackFcn(app, @call_lpf, true);
            app.LPFButton_2.Text = 'LPF';
            app.InphaseDemodulatedSignalButton = uibutton(app.SimulationTab, 'push');
            app.InphaseDemodulatedSignalButton.ButtonPushedFcn = createCallbackFcn(app, @call_midt, true);
            app.InphaseDemodulatedSignalButton.Text = {'Inphase'; 'Demodulated Signal'};
            app.XLabel = uilabel(app.SimulationTab);
            app.XLabel.Text = 'X';
            app.degreePhaseshifterLabel = uilabel(app.SimulationTab);
            app.degreePhaseshifterLabel.Text = {'-90 degree'; 'Phase shifter'};
            app.XLabel_2 = uilabel(app.SimulationTab);
            app.XLabel_2.Text = 'X';
            app.Label_20 = uilabel(app.SimulationTab);
            app.Label_20.Text = '------------------------------';
            app.Label_21 = uilabel(app.SimulationTab);
            app.Label_21.Text = '------------------------------';
            app.Label_22 = uilabel(app.SimulationTab);
            app.Label_22.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_23 = uilabel(app.SimulationTab);
            app.Label_23.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_24 = uilabel(app.SimulationTab);
            app.Label_24.Text = '+';
            app.Label_25 = uilabel(app.SimulationTab);
            app.Label_25.Text = '-';
            app.XLabel_3 = uilabel(app.SimulationTab);
            app.XLabel_3.Text = 'X';
            app.degreePhaseshifterLabel_2 = uilabel(app.SimulationTab);
            app.degreePhaseshifterLabel_2.Text = {'-90 degree'; 'Phase shifter'};
            app.XLabel_4 = uilabel(app.SimulationTab);
            app.XLabel_4.Text = 'X';
            app.Label_26 = uilabel(app.SimulationTab);
            app.Label_26.Text = '--------';
            app.Label_27 = uilabel(app.SimulationTab);
            app.Label_27.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_28 = uilabel(app.SimulationTab);
            app.Label_28.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_29 = uilabel(app.SimulationTab);
            app.Label_29.Text = '--------';
            app.Label_30 = uilabel(app.SimulationTab);
            app.Label_30.Text = '--------';
            app.Image2 = uiimage(app.SimulationTab);
            app.Image2.ImageSource = 'arrow.png';
            app.Image2_2 = uiimage(app.SimulationTab);
            app.Image2_2.ImageSource = 'arrow.png';
            app.Image2_3 = uiimage(app.SimulationTab);
            app.Image2_3.ImageSource = 'arrow.png';
            app.Image2_4 = uiimage(app.SimulationTab);
            app.Image2_4.ImageSource = 'arrow.png';
            app.Image2_5 = uiimage(app.SimulationTab);
            app.Image2_5.ImageSource = 'arrow.png';
            app.Image2_6 = uiimage(app.SimulationTab);
            app.Image2_6.ImageSource = 'arrow.png';
            app.Image2_7 = uiimage(app.SimulationTab);
            app.Image2_7.ImageSource = 'arrow.png';
            app.Image2_8 = uiimage(app.SimulationTab);
            app.Image2_8.ImageSource = 'arrow.png';
            app.Image9 = uiimage(app.SimulationTab);
            app.Image9.ImageSource = 'arrow1.png';
            app.Image9_2 = uiimage(app.SimulationTab);
            app.Image9_2.ImageSource = 'arrow1.png';
            app.Image10 = uiimage(app.SimulationTab);
            app.Image10.ImageSource = 'arrow2.png';
            app.Image10_2 = uiimage(app.SimulationTab);
            app.Image10_2.ImageSource = 'arrow2.png';
            app.Image10_3 = uiimage(app.SimulationTab);
            app.Image10_3.ImageSource = 'arrow2.png';
            app.Image10_4 = uiimage(app.SimulationTab);
            app.Image10_4.ImageSource = 'arrow2.png';
            app.Label_31 = uilabel(app.SimulationTab);
            app.Label_31.Text = '-------------------------------------------------------------------------------';
            app.Label_32 = uilabel(app.SimulationTab);
            app.Label_32.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_33 = uilabel(app.SimulationTab);
            app.Label_33.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.QAMTransmitterLabel = uilabel(app.SimulationTab);
            app.QAMTransmitterLabel.Text = 'QAM Transmitter';
            app.Label_34 = uilabel(app.SimulationTab);
            app.Label_34.Text = '-----------------------------------------------------------------------------------';
            app.Label_35 = uilabel(app.SimulationTab);
            app.Label_35.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_36 = uilabel(app.SimulationTab);
            app.Label_36.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.QAMReceiverLabel = uilabel(app.SimulationTab);
            app.QAMReceiverLabel.Text = 'QAM Receiver';
            app.TabGroup = uitabgroup(app.SimulationTab);
            app.InphasemtTab = uitab(app.TabGroup);
            app.InphasemtTab.Title = 'Inphase m(t)';
            app.UIAxes = uiaxes(app.InphasemtTab);
            title(app.UIAxes, 'Frequency Domain')
            xlabel(app.UIAxes, 'Frequency--->')
            ylabel(app.UIAxes, 'DFT Values--->')
            app.UIAxes2 = uiaxes(app.InphasemtTab);
            title(app.UIAxes2, 'Time Domain')
            xlabel(app.UIAxes2, 'Time--->')
            ylabel(app.UIAxes2, 'Amplitude-->')
            app.AmplitudeVSliderLabel = uilabel(app.InphasemtTab);
            app.AmplitudeVSliderLabel.Text = 'Amplitude (V)';
            app.AmplitudeVSlider = uislider(app.InphasemtTab);
            app.AmplitudeVSlider.Limits = [0 2];
            app.FrequencyHzSliderLabel = uilabel(app.InphasemtTab);
            app.FrequencyHzSliderLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider = uislider(app.InphasemtTab);
            app.FrequencyHzSlider.Limits = [0 5];
            app.QuadraturemtTab = uitab(app.TabGroup);
            app.QuadraturemtTab.Title = 'Quadrature m(t)';
            app.UIAxes2_2 = uiaxes(app.QuadraturemtTab);
            title(app.UIAxes2_2, 'Time Domain')
            xlabel(app.UIAxes2_2, 'Time--->')
            ylabel(app.UIAxes2_2, 'Amplitude-->')
            app.UIAxes_2 = uiaxes(app.QuadraturemtTab);
            title(app.UIAxes_2, 'Frequency Domain')
            xlabel(app.UIAxes_2, 'Frequency--->')
            ylabel(app.UIAxes_2, 'DFT Values--->')
            app.AmplitudeVSlider_2Label = uilabel(app.QuadraturemtTab);
            app.AmplitudeVSlider_2Label.Text = 'Amplitude (V)';
            app.AmplitudeVSlider_2 = uislider(app.QuadraturemtTab);
            app.AmplitudeVSlider_2.Limits = [0 2];
            app.FrequencyHzSlider_2Label = uilabel(app.QuadraturemtTab);
            app.FrequencyHzSlider_2Label.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider_2 = uislider(app.QuadraturemtTab);
            app.FrequencyHzSlider_2.Limits = [0 5];
            app.CarrierSignalTab = uitab(app.TabGroup);
            app.CarrierSignalTab.Title = 'Carrier Signal';
            app.UIAxes2_3 = uiaxes(app.CarrierSignalTab);
            title(app.UIAxes2_3, 'Time Domain')
            xlabel(app.UIAxes2_3, 'Time--->')
            ylabel(app.UIAxes2_3, 'Amplitude-->')
            app.UIAxes_3 = uiaxes(app.CarrierSignalTab);
            title(app.UIAxes_3, 'Frequency Domain')
            xlabel(app.UIAxes_3, 'Frequency--->')
            ylabel(app.UIAxes_3, 'DFT Values--->')
            app.FrequencyHzEditFieldLabel = uilabel(app.CarrierSignalTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency(Hz)';
            app.FrequencyHzEditField = uieditfield(app.CarrierSignalTab, 'numeric');
            app.AmplitudeVSlider_3Label = uilabel(app.CarrierSignalTab);
            app.AmplitudeVSlider_3Label.Text = 'Amplitude (V)';
            app.AmplitudeVSlider_3 = uislider(app.CarrierSignalTab);
            app.AmplitudeVSlider_3.Limits = [0 2];
            app.xtTab = uitab(app.TabGroup);
            app.xtTab.Title = 'x(t)';
            app.UIAxes2_4 = uiaxes(app.xtTab);
            title(app.UIAxes2_4, 'Time Domain')
            xlabel(app.UIAxes2_4, 'Time--->')
            ylabel(app.UIAxes2_4, 'Amplitude-->')
            app.UIAxes_4 = uiaxes(app.xtTab);
            title(app.UIAxes_4, 'Frequency Domain')
            xlabel(app.UIAxes_4, 'Frequency--->')
            ylabel(app.UIAxes_4, 'DFT Values--->')
            app.VCOTab = uitab(app.TabGroup);
            app.VCOTab.Title = 'VCO';
            app.UIAxes2_7 = uiaxes(app.VCOTab);
            title(app.UIAxes2_7, 'Time Domain')
            xlabel(app.UIAxes2_7, 'Time--->')
            ylabel(app.UIAxes2_7, 'Amplitude-->')
            app.UIAxes_8 = uiaxes(app.VCOTab);
            title(app.UIAxes_8, 'Frequency Domain')
            xlabel(app.UIAxes_8, 'Frequency--->')
            ylabel(app.UIAxes_8, 'DFT Values--->')
            app.LPFTab = uitab(app.TabGroup);
            app.LPFTab.Title = 'LPF';
            app.UIAxes_7 = uiaxes(app.LPFTab);
            title(app.UIAxes_7, 'Frequency Domain')
            xlabel(app.UIAxes_7, 'Frequency--->')
            ylabel(app.UIAxes_7, 'DFT Values--->')
            app.InphaseDemodulatedSignalTab = uitab(app.TabGroup);
            app.InphaseDemodulatedSignalTab.Title = 'Inphase Demodulated Signal';
            app.UIAxes2_5 = uiaxes(app.InphaseDemodulatedSignalTab);
            title(app.UIAxes2_5, 'Time Domain')
            xlabel(app.UIAxes2_5, 'Time--->')
            ylabel(app.UIAxes2_5, 'Amplitude-->')
            app.UIAxes_5 = uiaxes(app.InphaseDemodulatedSignalTab);
            title(app.UIAxes_5, 'Frequency Domain')
            xlabel(app.UIAxes_5, 'Frequency--->')
            ylabel(app.UIAxes_5, 'DFT Values--->')
            app.QuadratureDemodulatedSignalTab = uitab(app.TabGroup);
            app.QuadratureDemodulatedSignalTab.Title = 'Quadrature Demodulated Signal';
            app.UIAxes2_6 = uiaxes(app.QuadratureDemodulatedSignalTab);
            title(app.UIAxes2_6, 'Time Domain')
            xlabel(app.UIAxes2_6, 'Time--->')
            ylabel(app.UIAxes2_6, 'Amplitude-->')
            app.UIAxes_6 = uiaxes(app.QuadratureDemodulatedSignalTab);
            title(app.UIAxes_6, 'Frequency Domain')
            xlabel(app.UIAxes_6, 'Frequency--->')
            ylabel(app.UIAxes_6, 'DFT Values--->')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QAM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end