classdef SSB_mod < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SSBModulationLabel             matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        aaaaLabel                      matlab.ui.control.Label
        aaaaLabel_2                    matlab.ui.control.Label
        TabGroup5                      matlab.ui.container.TabGroup
        TheoryTab                      matlab.ui.container.Tab
        TabGroup6                      matlab.ui.container.TabGroup
        TheoryPg1Tab                   matlab.ui.container.Tab
        Image8                         matlab.ui.control.Image
        Image9                         matlab.ui.control.Image
        TheoryPg2Tab                   matlab.ui.container.Tab
        Image14                        matlab.ui.control.Image
        Image13                        matlab.ui.control.Image
        FrequencyDiscriminationTab     matlab.ui.container.Tab
        Image10                        matlab.ui.control.Image
        Image11                        matlab.ui.control.Image
        FrequencyDiscriminationPg2Tab  matlab.ui.container.Tab
        Image12                        matlab.ui.control.Image
        Image15                        matlab.ui.control.Image
        SimulationTab                  matlab.ui.container.Tab
        MessagesignalmtButton          matlab.ui.control.Button
        CarrierSignalCtButton          matlab.ui.control.Button
        DSBSCModulatedSignalButton     matlab.ui.control.Button
        LPFButton                      matlab.ui.control.Button
        HPFButton                      matlab.ui.control.Button
        LSBSignalButton                matlab.ui.control.Button
        USBSignalButton                matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        XLabel                         matlab.ui.control.Label
        Image2_2                       matlab.ui.control.Image
        Image2_3                       matlab.ui.control.Image
        Image2_4                       matlab.ui.control.Image
        Image6                         matlab.ui.control.Image
        Image2_5                       matlab.ui.control.Image
        Image6_2                       matlab.ui.control.Image
        Image7                         matlab.ui.control.Image
        TabGroup                       matlab.ui.container.TabGroup
        MessageSignalTab               matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        TimeDomainTab                  matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        FrequencyDomainTab             matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        AmplitudeVSliderLabel          matlab.ui.control.Label
        AmplitudeVSlider               matlab.ui.control.Slider
        FrequencyHzSliderLabel         matlab.ui.control.Label
        FrequencyHzSlider              matlab.ui.control.Slider
        CarrierSignalTab               matlab.ui.container.Tab
        TabGroup2_2                    matlab.ui.container.TabGroup
        TimeDomainTab_2                matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        FrequencyDomainTab_2           matlab.ui.container.Tab
        UIAxes2_2                      matlab.ui.control.UIAxes
        AmplitudeVSlider_2Label        matlab.ui.control.Label
        AmplitudeVSlider_2             matlab.ui.control.Slider
        FrequencyHzEditFieldLabel      matlab.ui.control.Label
        FrequencyHzEditField           matlab.ui.control.NumericEditField
        DSBModulatedSignalTab          matlab.ui.container.Tab
        TabGroup2_3                    matlab.ui.container.TabGroup
        TimeDomainTab_3                matlab.ui.container.Tab
        UIAxes_2                       matlab.ui.control.UIAxes
        FrequencyDomainTab_3           matlab.ui.container.Tab
        UIAxes2_3                      matlab.ui.control.UIAxes
        LPFTab                         matlab.ui.container.Tab
        UIAxes4                        matlab.ui.control.UIAxes
        HPFTab                         matlab.ui.container.Tab
        UIAxes5                        matlab.ui.control.UIAxes
        LSBSignalTab                   matlab.ui.container.Tab
        TabGroup4                      matlab.ui.container.TabGroup
        TimedomainTab                  matlab.ui.container.Tab
        UIAxes6                        matlab.ui.control.UIAxes
        FrequnecyDomainTab             matlab.ui.container.Tab
        UIAxes7                        matlab.ui.control.UIAxes
        USBSignalTab                   matlab.ui.container.Tab
        TabGroup4_2                    matlab.ui.container.TabGroup
        TimedomainTab_2                matlab.ui.container.Tab
        UIAxes6_2                      matlab.ui.control.UIAxes
        FrequnecyDomainTab_2           matlab.ui.container.Tab
        UIAxes7_2                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MessagesignalmtButton
        function call_msg(app, event)
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            plot(app.UIAxes,t,mt);
           
            N=length(mt);
            M=fftshift(fft(mt,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes2,f,abs(M/fs));
        
            app.MessagesignalmtButton.BackgroundColor=[0.7725 0.8902 0.8706];
        end

        % Button pushed function: CarrierSignalCtButton
        function call_carrier(app, event)
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            fs=1000;
            ct=v_c*cos(2*pi*f_c*t);
          
            plot(app.UIAxes3,t,ct);
             N=length(ct);
            M=fftshift(fft(ct,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes2_2,f,abs(M/fs));
           
            app.CarrierSignalCtButton.BackgroundColor=[0.9216 0.8078 0.8627];
        end

        % Button pushed function: DSBSCModulatedSignalButton
        function call_dsb(app, event)
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            ct=v_c*cos(2*pi*f_c*t);
            dsb=mt.*ct;
            plot(app.UIAxes_2,t,dsb);
           
            N=length(dsb);
            M=fftshift(fft(dsb,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes2_3,f,abs(M/fs));
           
            app.DSBSCModulatedSignalButton.BackgroundColor=[0.7725 0.8431 0.9412];
        end

        % Button pushed function: LPFButton
        function call_lpf(app, event)
            f_c=app.FrequencyHzEditField.Value;
            ft=-500:500;
            c1=[zeros(1,500-f_c) ones(1,2*f_c+1) zeros(1,500-f_c)];
            plot(app.UIAxes4, ft,c1);
            app.LPFButton.BackgroundColor=[0.8667 0.9216 0.9098];
        end

        % Button pushed function: HPFButton
        function call_hpf(app, event)
            f_c=app.FrequencyHzEditField.Value;
            ft=-500:500;
            c2=[ones(1,500-f_c) zeros(1,2*f_c+1) ones(1,500-f_c)];
            plot(app.UIAxes5, ft,c2);
            app.HPFButton.BackgroundColor=[0.9216 0.8196 0.8196];
        end

        % Button pushed function: LSBSignalButton
        function call_lsb(app, event)
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            ct=v_c*cos(2*pi*f_c*t);
            dsb=mt.*ct;
           
            N=length(dsb);
            M=fftshift(fft(dsb,N));
            f=fs*(-N/2:N/2-1)/N;
 
            c1=[zeros(1,500-f_c) ones(1,2*f_c+1) zeros(1,500-f_c)];
            lsb=abs(M/fs).*c1;
            lsb_time=ifft(ifftshift(lsb));
            plot(app.UIAxes6,t,lsb_time*fs);
            plot(app.UIAxes7,f,lsb);
            app.LSBSignalButton.BackgroundColor=[0.7804 0.9216 0.8863];
        end

        % Button pushed function: USBSignalButton
        function call_usb(app, event)
             v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            ct=v_c*cos(2*pi*f_c*t);
            dsb=mt.*ct;
           
            N=length(dsb);
            M=fftshift(fft(dsb,N));
            f=fs*(-N/2:N/2-1)/N;
 
            c2=[ones(1,500-f_c) zeros(1,2*f_c+1) ones(1,500-f_c)];
            usb=abs(M/fs).*c2;
            usb_time=ifft(ifftshift(usb));
            plot(app.UIAxes6_2,t,usb_time*fs);
            plot(app.UIAxes7_2,f,usb);
            app.USBSignalButton.BackgroundColor=[0.9294 0.8549 0.8549];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SSBModulationLabel = uilabel(app.UIFigure);
            app.SSBModulationLabel.Text = 'SSB Modulation';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'DTU,_Delhi_official_logo.png';
            app.Label = uilabel(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.aaaaLabel = uilabel(app.UIFigure);
            app.aaaaLabel.Text = {'a'; 'a'; 'a'; 'a'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; ''; '.'; ''; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '..'; '.'; '.'; ''; '..'; ''; '.'; '.'; ''; '.'; '.'; '..'; ''; '.'; '..'; ''; '.'; ''; ''; ''; ''; ''; ''};
            app.aaaaLabel_2 = uilabel(app.UIFigure);
            app.aaaaLabel_2.Text = {'a'; 'a'; 'a'; 'a'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; ''; '.'; ''; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '..'; '.'; '.'; ''; '..'; ''; '.'; '.'; ''; '.'; '.'; '..'; ''; '.'; '..'; ''; '.'; ''; ''; ''; ''; ''; ''};
            app.TabGroup5 = uitabgroup(app.UIFigure);
            app.TheoryTab = uitab(app.TabGroup5);
            app.TheoryTab.Title = 'Theory';
            app.TabGroup6 = uitabgroup(app.TheoryTab);
            app.TheoryPg1Tab = uitab(app.TabGroup6);
            app.TheoryPg1Tab.Title = 'Theory Pg-1';
            app.Image8 = uiimage(app.TheoryPg1Tab);
            app.Image8.ImageSource = 'ssb1.png';
            app.Image9 = uiimage(app.TheoryPg1Tab);
            app.Image9.ImageSource = 'ssb2.png';
            app.TheoryPg2Tab = uitab(app.TabGroup6);
            app.TheoryPg2Tab.Title = 'Theory Pg-2';
            app.Image14 = uiimage(app.TheoryPg2Tab);
            app.Image14.ImageSource = 'ssb4.png';
            app.Image13 = uiimage(app.TheoryPg2Tab);
            app.Image13.ImageSource = 'ssb3.png';
            app.FrequencyDiscriminationTab = uitab(app.TabGroup6);
            app.FrequencyDiscriminationTab.Title = 'Frequency Discrimination';
            app.Image10 = uiimage(app.FrequencyDiscriminationTab);
            app.Image10.ImageSource = 'ssb7.png';
            app.Image11 = uiimage(app.FrequencyDiscriminationTab);
            app.Image11.ImageSource = 'ssb5.png';
            app.FrequencyDiscriminationPg2Tab = uitab(app.TabGroup6);
            app.FrequencyDiscriminationPg2Tab.Title = 'Frequency Discrimination Pg-2';
            app.Image12 = uiimage(app.FrequencyDiscriminationPg2Tab);
            app.Image12.ImageSource = 'ssb6.png';
            app.Image15 = uiimage(app.FrequencyDiscriminationPg2Tab);
            app.Image15.ImageSource = 'ssb8.png';
            app.SimulationTab = uitab(app.TabGroup5);
            app.SimulationTab.Title = 'Simulation';
            app.MessagesignalmtButton = uibutton(app.SimulationTab, 'push');
            app.MessagesignalmtButton.ButtonPushedFcn = createCallbackFcn(app, @call_msg, true);
            app.MessagesignalmtButton.Text = {'Message signal'; 'm(t)'};
            app.CarrierSignalCtButton = uibutton(app.SimulationTab, 'push');
            app.CarrierSignalCtButton.ButtonPushedFcn = createCallbackFcn(app, @call_carrier, true);
            app.CarrierSignalCtButton.Text = {'Carrier Signal'; 'C(t)'};
            app.DSBSCModulatedSignalButton = uibutton(app.SimulationTab, 'push');
            app.DSBSCModulatedSignalButton.ButtonPushedFcn = createCallbackFcn(app, @call_dsb, true);
            app.DSBSCModulatedSignalButton.Text = {'DSB-SC '; 'Modulated Signal'; ''};
            app.LPFButton = uibutton(app.SimulationTab, 'push');
            app.LPFButton.ButtonPushedFcn = createCallbackFcn(app, @call_lpf, true);
            app.LPFButton.Text = 'LPF';
            app.HPFButton = uibutton(app.SimulationTab, 'push');
            app.HPFButton.ButtonPushedFcn = createCallbackFcn(app, @call_hpf, true);
            app.HPFButton.Text = 'HPF';
            app.LSBSignalButton = uibutton(app.SimulationTab, 'push');
            app.LSBSignalButton.ButtonPushedFcn = createCallbackFcn(app, @call_lsb, true);
            app.LSBSignalButton.Text = {'LSB'; 'Signal'};
            app.USBSignalButton = uibutton(app.SimulationTab, 'push');
            app.USBSignalButton.ButtonPushedFcn = createCallbackFcn(app, @call_usb, true);
            app.USBSignalButton.Text = {'USB '; 'Signal'};
            app.Image2 = uiimage(app.SimulationTab);
            app.Image2.ImageSource = 'arrow.png';
            app.XLabel = uilabel(app.SimulationTab);
            app.XLabel.Text = 'X';
            app.Image2_2 = uiimage(app.SimulationTab);
            app.Image2_2.ImageSource = 'arrow.png';
            app.Image2_3 = uiimage(app.SimulationTab);
            app.Image2_3.ImageSource = 'arrow.png';
            app.Image2_4 = uiimage(app.SimulationTab);
            app.Image2_4.ImageSource = 'arrow.png';
            app.Image6 = uiimage(app.SimulationTab);
            app.Image6.ImageSource = 'arrow1.png';
            app.Image2_5 = uiimage(app.SimulationTab);
            app.Image2_5.ImageSource = 'arrow.png';
            app.Image6_2 = uiimage(app.SimulationTab);
            app.Image6_2.ImageSource = 'arrow1.png';
            app.Image7 = uiimage(app.SimulationTab);
            app.Image7.ImageSource = 'arrow2.png';
            app.TabGroup = uitabgroup(app.SimulationTab);
            app.MessageSignalTab = uitab(app.TabGroup);
            app.MessageSignalTab.Title = 'Message Signal';
            app.TabGroup2 = uitabgroup(app.MessageSignalTab);
            app.TimeDomainTab = uitab(app.TabGroup2);
            app.TimeDomainTab.Title = 'Time Domain';
            app.UIAxes = uiaxes(app.TimeDomainTab);
            title(app.UIAxes, 'Message Signal')
            xlabel(app.UIAxes, 'Time--->')
            ylabel(app.UIAxes, 'Amplitude--->')
            app.FrequencyDomainTab = uitab(app.TabGroup2);
            app.FrequencyDomainTab.Title = 'Frequency Domain';
            app.UIAxes2 = uiaxes(app.FrequencyDomainTab);
            title(app.UIAxes2, 'Message Signal')
            xlabel(app.UIAxes2, 'Time--->')
            ylabel(app.UIAxes2, 'DFT Values-->')
            app.AmplitudeVSliderLabel = uilabel(app.MessageSignalTab);
            app.AmplitudeVSliderLabel.Text = 'Amplitude (V)';
            app.AmplitudeVSlider = uislider(app.MessageSignalTab);
            app.AmplitudeVSlider.Limits = [0 2];
            app.FrequencyHzSliderLabel = uilabel(app.MessageSignalTab);
            app.FrequencyHzSliderLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider = uislider(app.MessageSignalTab);
            app.FrequencyHzSlider.Limits = [0 5];
            app.CarrierSignalTab = uitab(app.TabGroup);
            app.CarrierSignalTab.Title = 'Carrier Signal';
            app.TabGroup2_2 = uitabgroup(app.CarrierSignalTab);
            app.TimeDomainTab_2 = uitab(app.TabGroup2_2);
            app.TimeDomainTab_2.Title = 'Time Domain';
            app.UIAxes3 = uiaxes(app.TimeDomainTab_2);
            title(app.UIAxes3, 'Carrier Signal')
            xlabel(app.UIAxes3, 'Time--->')
            ylabel(app.UIAxes3, 'Amplitude--->')
            app.FrequencyDomainTab_2 = uitab(app.TabGroup2_2);
            app.FrequencyDomainTab_2.Title = 'Frequency Domain';
            app.UIAxes2_2 = uiaxes(app.FrequencyDomainTab_2);
            title(app.UIAxes2_2, 'Carrier Signal')
            xlabel(app.UIAxes2_2, 'Time--->')
            ylabel(app.UIAxes2_2, 'DFT Values-->')
            app.AmplitudeVSlider_2Label = uilabel(app.CarrierSignalTab);
            app.AmplitudeVSlider_2Label.Text = 'Amplitude (V)';
            app.AmplitudeVSlider_2 = uislider(app.CarrierSignalTab);
            app.AmplitudeVSlider_2.Limits = [0 2];
            app.FrequencyHzEditFieldLabel = uilabel(app.CarrierSignalTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency(Hz)';
            app.FrequencyHzEditField = uieditfield(app.CarrierSignalTab, 'numeric');
            app.DSBModulatedSignalTab = uitab(app.TabGroup);
            app.DSBModulatedSignalTab.Title = 'DSB Modulated Signal';
            app.TabGroup2_3 = uitabgroup(app.DSBModulatedSignalTab);
            app.TimeDomainTab_3 = uitab(app.TabGroup2_3);
            app.TimeDomainTab_3.Title = 'Time Domain';
            app.UIAxes_2 = uiaxes(app.TimeDomainTab_3);
            title(app.UIAxes_2, 'DSB-SC Signal')
            xlabel(app.UIAxes_2, 'Time--->')
            ylabel(app.UIAxes_2, 'Amplitude--->')
            app.FrequencyDomainTab_3 = uitab(app.TabGroup2_3);
            app.FrequencyDomainTab_3.Title = 'Frequency Domain';
            app.UIAxes2_3 = uiaxes(app.FrequencyDomainTab_3);
            title(app.UIAxes2_3, 'DSB-SC Signal')
            xlabel(app.UIAxes2_3, 'Time--->')
            ylabel(app.UIAxes2_3, 'DFT Values-->')
            app.LPFTab = uitab(app.TabGroup);
            app.LPFTab.Title = 'LPF';
            app.UIAxes4 = uiaxes(app.LPFTab);
            title(app.UIAxes4, 'Low Pass Filter')
            xlabel(app.UIAxes4, 'Frequency-->')
            ylabel(app.UIAxes4, 'DFT Values')
            app.UIAxes4.YLim = [0 2];
            app.HPFTab = uitab(app.TabGroup);
            app.HPFTab.Title = 'HPF';
            app.UIAxes5 = uiaxes(app.HPFTab);
            title(app.UIAxes5, 'High Pass Filter')
            xlabel(app.UIAxes5, 'frequency-->')
            ylabel(app.UIAxes5, 'DFT Values-->')
            app.UIAxes5.YLim = [0 2];
            app.LSBSignalTab = uitab(app.TabGroup);
            app.LSBSignalTab.Title = 'LSB Signal';
            app.TabGroup4 = uitabgroup(app.LSBSignalTab);
            app.TimedomainTab = uitab(app.TabGroup4);
            app.TimedomainTab.Title = 'Time domain';
            app.UIAxes6 = uiaxes(app.TimedomainTab);
            title(app.UIAxes6, 'LSB Signal')
            xlabel(app.UIAxes6, 'Time--->')
            ylabel(app.UIAxes6, 'Amplitude--->')
            app.FrequnecyDomainTab = uitab(app.TabGroup4);
            app.FrequnecyDomainTab.Title = 'Frequnecy Domain';
            app.UIAxes7 = uiaxes(app.FrequnecyDomainTab);
            title(app.UIAxes7, 'LSB Signal')
            xlabel(app.UIAxes7, 'time-->')
            ylabel(app.UIAxes7, 'DFT Values-->')
            app.USBSignalTab = uitab(app.TabGroup);
            app.USBSignalTab.Title = 'USB Signal';
            app.TabGroup4_2 = uitabgroup(app.USBSignalTab);
            app.TimedomainTab_2 = uitab(app.TabGroup4_2);
            app.TimedomainTab_2.Title = 'Time domain';
            app.UIAxes6_2 = uiaxes(app.TimedomainTab_2);
            title(app.UIAxes6_2, 'USB Signal')
            xlabel(app.UIAxes6_2, 'Time--->')
            ylabel(app.UIAxes6_2, 'Amplitude--->')
            app.FrequnecyDomainTab_2 = uitab(app.TabGroup4_2);
            app.FrequnecyDomainTab_2.Title = 'Frequnecy Domain';
            app.UIAxes7_2 = uiaxes(app.FrequnecyDomainTab_2);
            title(app.UIAxes7_2, 'USB Signal')
            xlabel(app.UIAxes7_2, 'time-->')
            ylabel(app.UIAxes7_2, 'DFT Values-->')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SSB_mod

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end