classdef VSB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image                       matlab.ui.control.Image
        VSBModulationLabel          matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        aaaaLabel                   matlab.ui.control.Label
        aaaaLabel_2                 matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        aaaaLabel_3                 matlab.ui.control.Label
        TabGroup7                   matlab.ui.container.TabGroup
        TheoryTab                   matlab.ui.container.Tab
        TabGroup8                   matlab.ui.container.TabGroup
        Page1Tab                    matlab.ui.container.Tab
        Image8                      matlab.ui.control.Image
        Image9                      matlab.ui.control.Image
        Image12                     matlab.ui.control.Image
        Page2Tab                    matlab.ui.container.Tab
        Image10                     matlab.ui.control.Image
        Image11                     matlab.ui.control.Image
        Image13                     matlab.ui.control.Image
        SimulationTab               matlab.ui.container.Tab
        MessagesignalmtButton       matlab.ui.control.Button
        CarrierSignalCtButton       matlab.ui.control.Button
        DSBSCModulatedSignalButton  matlab.ui.control.Button
        LVPFButton                  matlab.ui.control.Button
        HVPFButton                  matlab.ui.control.Button
        LVSBSignalButton            matlab.ui.control.Button
        UVSBSignalButton            matlab.ui.control.Button
        Image2                      matlab.ui.control.Image
        XLabel                      matlab.ui.control.Label
        Image2_2                    matlab.ui.control.Image
        Image2_3                    matlab.ui.control.Image
        Image2_4                    matlab.ui.control.Image
        Image6                      matlab.ui.control.Image
        Image2_5                    matlab.ui.control.Image
        Image6_2                    matlab.ui.control.Image
        Image7                      matlab.ui.control.Image
        TabGroup_2                  matlab.ui.container.TabGroup
        MessageSignalTab_2          matlab.ui.container.Tab
        TabGroup2_4                 matlab.ui.container.TabGroup
        TimeDomainTab_4             matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        FrequencyDomainTab_4        matlab.ui.container.Tab
        UIAxes2                     matlab.ui.control.UIAxes
        AmplitudeVSliderLabel       matlab.ui.control.Label
        AmplitudeVSlider            matlab.ui.control.Slider
        FrequencyHzSliderLabel      matlab.ui.control.Label
        FrequencyHzSlider           matlab.ui.control.Slider
        CarrierSignalTab_2          matlab.ui.container.Tab
        TabGroup2_5                 matlab.ui.container.TabGroup
        TimeDomainTab_5             matlab.ui.container.Tab
        UIAxes3                     matlab.ui.control.UIAxes
        FrequencyDomainTab_5        matlab.ui.container.Tab
        UIAxes2_2                   matlab.ui.control.UIAxes
        AmplitudeVSlider_2Label     matlab.ui.control.Label
        AmplitudeVSlider_2          matlab.ui.control.Slider
        FrequencyHzEditFieldLabel   matlab.ui.control.Label
        FrequencyHzEditField        matlab.ui.control.NumericEditField
        DSBModulatedSignalTab       matlab.ui.container.Tab
        TabGroup2_6                 matlab.ui.container.TabGroup
        TimeDomainTab_6             matlab.ui.container.Tab
        UIAxes_2                    matlab.ui.control.UIAxes
        FrequencyDomainTab_6        matlab.ui.container.Tab
        UIAxes2_3                   matlab.ui.control.UIAxes
        LPFTab                      matlab.ui.container.Tab
        UIAxes4                     matlab.ui.control.UIAxes
        HPFTab                      matlab.ui.container.Tab
        UIAxes5                     matlab.ui.control.UIAxes
        LVSBSignalTab               matlab.ui.container.Tab
        TabGroup4                   matlab.ui.container.TabGroup
        TimedomainTab               matlab.ui.container.Tab
        UIAxes6                     matlab.ui.control.UIAxes
        FrequnecyDomainTab          matlab.ui.container.Tab
        UIAxes7                     matlab.ui.control.UIAxes
        UVSBSignalTab               matlab.ui.container.Tab
        TabGroup4_2                 matlab.ui.container.TabGroup
        TimedomainTab_2             matlab.ui.container.Tab
        UIAxes6_2                   matlab.ui.control.UIAxes
        FrequnecyDomainTab_2        matlab.ui.container.Tab
        UIAxes7_2                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LVPFButton
        function LVPFButtonPushed(app, event)
            f_c=app.FrequencyHzEditField.Value;
            ft_1=-500:-f_c-1;
            ft_2=-f_c:f_c;
            ft_3=f_c+1:500;
            ft=[ft_1 ft_2 ft_3];
            c1=[zeros(1,500-f_c) ones(1,2*f_c+1) zeros(1,500-f_c)];
            plot(app.UIAxes4, ft,c1);
            app.LVPFButton.BackgroundColor=[0.8667 0.9216 0.9098];
        end

        % Button pushed function: HVPFButton
        function HVPFButtonPushed(app, event)
            f_c=app.FrequencyHzEditField.Value;
            ft_1=-500:-f_c-1;
            ft_2=-f_c:f_c;
            ft_3=f_c+1:500;
            ft=[ft_1 ft_2 ft_3];
            c2=[ones(1,500-f_c) zeros(1,2*f_c+1) ones(1,500-f_c)];
            plot(app.UIAxes5, ft,c2);
            app.HVPFButton.BackgroundColor=[0.9216 0.8196 0.8196];
        end

        % Button pushed function: MessagesignalmtButton
        function MessagesignalmtButtonPushed(app, event)
             v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            plot(app.UIAxes,t,mt);
            N=length(mt);
            M=fftshift(fft(mt,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes2,f,abs(M/fs));
            app.MessagesignalmtButton.BackgroundColor=[0.7725 0.8902 0.8706];
        end

        % Button pushed function: CarrierSignalCtButton
        function CarrierSignalCtButtonPushed(app, event)
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            fs=1000;
            ct=v_c*cos(2*pi*f_c*t);
            plot(app.UIAxes3,t,ct);
             N=length(ct);
            M=fftshift(fft(ct,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes2_2,f,abs(M/fs));
            app.CarrierSignalCtButton.BackgroundColor=[0.9216 0.8078 0.8627];
        end

        % Button pushed function: DSBSCModulatedSignalButton
        function DSBSCModulatedSignalButtonPushed(app, event)
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            ct=v_c*cos(2*pi*f_c*t);
            dsb=mt.*ct;
            plot(app.UIAxes_2,t,dsb);
            N=length(dsb);
            M=fftshift(fft(dsb,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes2_3,f,abs(M/fs));
            app.DSBSCModulatedSignalButton.BackgroundColor=[0.7725 0.8431 0.9412];
        end

        % Button pushed function: LVSBSignalButton
        function LVSBSignalButtonPushed(app, event)
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            ct=v_c*cos(2*pi*f_c*t);
            dsb=mt.*ct;
           
            N=length(dsb);
            M=fftshift(fft(dsb,N));
            f=fs*(-N/2:N/2-1)/N;
 
            c1=[zeros(1,500-f_c) ones(1,2*f_c+1) zeros(1,500-f_c)];
            lsb=abs(M/fs).*c1;
            lsb_time=ifft(ifftshift(lsb));
            plot(app.UIAxes6,t,lsb_time*fs);
            plot(app.UIAxes7,f,lsb);
            app.LVSBSignalButton.BackgroundColor=[0.7804 0.9216 0.8863];
        end

        % Button pushed function: UVSBSignalButton
        function UVSBSignalButtonPushed(app, event)
             v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzEditField.Value;
            t=0:1/1000:1;
            ct=v_c*cos(2*pi*f_c*t);
            dsb=mt.*ct;
           
            N=length(dsb);
            M=fftshift(fft(dsb,N));
            f=fs*(-N/2:N/2-1)/N;
 
            c2=[ones(1,500-f_c) zeros(1,2*f_c+1) ones(1,500-f_c)];
            usb=abs(M/fs).*c2;
            usb_time=ifft(ifftshift(usb));
            plot(app.UIAxes6_2,t,usb_time*fs);
            plot(app.UIAxes7_2,f,usb);
            app.UVSBSignalButton.BackgroundColor=[0.9294 0.8549 0.8549];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'DTU,_Delhi_official_logo.png';
            app.VSBModulationLabel = uilabel(app.UIFigure);
            app.VSBModulationLabel.Text = 'VSB Modulation';
            app.Label_2 = uilabel(app.UIFigure);
            app.aaaaLabel = uilabel(app.UIFigure);
            app.aaaaLabel.Text = {'a'; 'a'; 'a'; 'a'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; ''; '.'; ''; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '..'; '.'; '.'; ''; '..'; ''; '.'; '.'; ''; '.'; '.'; '..'; ''; '.'; '..'; ''; '.'; ''; ''; ''; ''; ''; ''};
            app.aaaaLabel_2 = uilabel(app.UIFigure);
            app.aaaaLabel_2.Text = {'a'; 'a'; 'a'; 'a'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; ''; '.'; ''; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '..'; '.'; '.'; ''; '..'; ''; '.'; '.'; ''; '.'; '.'; '..'; ''; '.'; '..'; ''; '.'; ''; ''; ''; ''; ''; ''};
            app.Label_3 = uilabel(app.UIFigure);
            app.aaaaLabel_3 = uilabel(app.UIFigure);
            app.aaaaLabel_3.Text = {'a'; 'a'; 'a'; 'a'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; ''; '.'; ''; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '..'; '.'; '.'; ''; '..'; ''; '.'; '.'; ''; '.'; '.'; '..'; ''; '.'; '..'; ''; '.'; ''; ''; ''; ''; ''; ''};
            app.TabGroup7 = uitabgroup(app.UIFigure);
            app.TheoryTab = uitab(app.TabGroup7);
            app.TheoryTab.Title = 'Theory';
            app.TabGroup8 = uitabgroup(app.TheoryTab);
            app.Page1Tab = uitab(app.TabGroup8);
            app.Page1Tab.Title = 'Page 1';
            app.Image8 = uiimage(app.Page1Tab);
            app.Image8.ImageSource = 'vsb1.png';
            app.Image9 = uiimage(app.Page1Tab);
            app.Image9.ImageSource = 'vsb2.png';
            app.Image12 = uiimage(app.Page1Tab);
            app.Image12.ImageSource = 'vsb5.png';
            app.Page2Tab = uitab(app.TabGroup8);
            app.Page2Tab.Title = 'Page 2';
            app.Image10 = uiimage(app.Page2Tab);
            app.Image10.ImageSource = 'vsb3.png';
            app.Image11 = uiimage(app.Page2Tab);
            app.Image11.ImageSource = 'vsb4.png';
            app.Image13 = uiimage(app.Page2Tab);
            app.Image13.ImageSource = 'vsb6.png';
            app.SimulationTab = uitab(app.TabGroup7);
            app.SimulationTab.Title = 'Simulation';
            app.MessagesignalmtButton = uibutton(app.SimulationTab, 'push');
            app.MessagesignalmtButton.ButtonPushedFcn = createCallbackFcn(app, @MessagesignalmtButtonPushed, true);
            app.MessagesignalmtButton.Text = {'Message signal'; 'm(t)'};
            app.CarrierSignalCtButton = uibutton(app.SimulationTab, 'push');
            app.CarrierSignalCtButton.ButtonPushedFcn = createCallbackFcn(app, @CarrierSignalCtButtonPushed, true);
            app.CarrierSignalCtButton.Text = {'Carrier Signal'; 'C(t)'};
            app.DSBSCModulatedSignalButton = uibutton(app.SimulationTab, 'push');
            app.DSBSCModulatedSignalButton.ButtonPushedFcn = createCallbackFcn(app, @DSBSCModulatedSignalButtonPushed, true);
            app.DSBSCModulatedSignalButton.Text = {'DSB-SC '; 'Modulated Signal'; ''};
            app.LVPFButton = uibutton(app.SimulationTab, 'push');
            app.LVPFButton.ButtonPushedFcn = createCallbackFcn(app, @LVPFButtonPushed, true);
            app.LVPFButton.Text = 'LVPF';
            app.HVPFButton = uibutton(app.SimulationTab, 'push');
            app.HVPFButton.ButtonPushedFcn = createCallbackFcn(app, @HVPFButtonPushed, true);
            app.HVPFButton.Text = 'HVPF';
            app.LVSBSignalButton = uibutton(app.SimulationTab, 'push');
            app.LVSBSignalButton.ButtonPushedFcn = createCallbackFcn(app, @LVSBSignalButtonPushed, true);
            app.LVSBSignalButton.Text = {'LVSB'; 'Signal'};
            app.UVSBSignalButton = uibutton(app.SimulationTab, 'push');
            app.UVSBSignalButton.ButtonPushedFcn = createCallbackFcn(app, @UVSBSignalButtonPushed, true);
            app.UVSBSignalButton.Text = {'UVSB '; 'Signal'};
            app.Image2 = uiimage(app.SimulationTab);
            app.Image2.ImageSource = 'arrow.png';
            app.XLabel = uilabel(app.SimulationTab);
            app.XLabel.Text = 'X';
            app.Image2_2 = uiimage(app.SimulationTab);
            app.Image2_2.ImageSource = 'arrow.png';
            app.Image2_3 = uiimage(app.SimulationTab);
            app.Image2_3.ImageSource = 'arrow.png';
            app.Image2_4 = uiimage(app.SimulationTab);
            app.Image2_4.ImageSource = 'arrow.png';
            app.Image6 = uiimage(app.SimulationTab);
            app.Image6.ImageSource = 'arrow1.png';
            app.Image2_5 = uiimage(app.SimulationTab);
            app.Image2_5.ImageSource = 'arrow.png';
            app.Image6_2 = uiimage(app.SimulationTab);
            app.Image6_2.ImageSource = 'arrow1.png';
            app.Image7 = uiimage(app.SimulationTab);
            app.Image7.ImageSource = 'arrow2.png';
            app.TabGroup_2 = uitabgroup(app.SimulationTab);
            app.MessageSignalTab_2 = uitab(app.TabGroup_2);
            app.MessageSignalTab_2.Title = 'Message Signal';
            app.TabGroup2_4 = uitabgroup(app.MessageSignalTab_2);
            app.TimeDomainTab_4 = uitab(app.TabGroup2_4);
            app.TimeDomainTab_4.Title = 'Time Domain';
            app.UIAxes = uiaxes(app.TimeDomainTab_4);
            title(app.UIAxes, 'Message Signal')
            xlabel(app.UIAxes, 'Time--->')
            ylabel(app.UIAxes, 'Amplitude--->')
            app.FrequencyDomainTab_4 = uitab(app.TabGroup2_4);
            app.FrequencyDomainTab_4.Title = 'Frequency Domain';
            app.UIAxes2 = uiaxes(app.FrequencyDomainTab_4);
            title(app.UIAxes2, 'Message Signal')
            xlabel(app.UIAxes2, 'Time--->')
            ylabel(app.UIAxes2, 'DFT Values-->')
            app.AmplitudeVSliderLabel = uilabel(app.MessageSignalTab_2);
            app.AmplitudeVSliderLabel.Text = 'Amplitude (V)';
            app.AmplitudeVSlider = uislider(app.MessageSignalTab_2);
            app.AmplitudeVSlider.Limits = [0 2];
            app.FrequencyHzSliderLabel = uilabel(app.MessageSignalTab_2);
            app.FrequencyHzSliderLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider = uislider(app.MessageSignalTab_2);
            app.FrequencyHzSlider.Limits = [0 5];
            app.CarrierSignalTab_2 = uitab(app.TabGroup_2);
            app.CarrierSignalTab_2.Title = 'Carrier Signal';
            app.TabGroup2_5 = uitabgroup(app.CarrierSignalTab_2);
            app.TimeDomainTab_5 = uitab(app.TabGroup2_5);
            app.TimeDomainTab_5.Title = 'Time Domain';
            app.UIAxes3 = uiaxes(app.TimeDomainTab_5);
            title(app.UIAxes3, 'Carrier Signal')
            xlabel(app.UIAxes3, 'Time--->')
            ylabel(app.UIAxes3, 'Amplitude--->')
            app.FrequencyDomainTab_5 = uitab(app.TabGroup2_5);
            app.FrequencyDomainTab_5.Title = 'Frequency Domain';
            app.UIAxes2_2 = uiaxes(app.FrequencyDomainTab_5);
            title(app.UIAxes2_2, 'Carrier Signal')
            xlabel(app.UIAxes2_2, 'Time--->')
            ylabel(app.UIAxes2_2, 'DFT Values-->')
            app.AmplitudeVSlider_2Label = uilabel(app.CarrierSignalTab_2);
            app.AmplitudeVSlider_2Label.Text = 'Amplitude (V)';
            app.AmplitudeVSlider_2 = uislider(app.CarrierSignalTab_2);
            app.AmplitudeVSlider_2.Limits = [0 2];
            app.FrequencyHzEditFieldLabel = uilabel(app.CarrierSignalTab_2);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency(Hz)';
            app.FrequencyHzEditField = uieditfield(app.CarrierSignalTab_2, 'numeric');
            app.DSBModulatedSignalTab = uitab(app.TabGroup_2);
            app.DSBModulatedSignalTab.Title = 'DSB Modulated Signal';
            app.TabGroup2_6 = uitabgroup(app.DSBModulatedSignalTab);
            app.TimeDomainTab_6 = uitab(app.TabGroup2_6);
            app.TimeDomainTab_6.Title = 'Time Domain';
            app.UIAxes_2 = uiaxes(app.TimeDomainTab_6);
            title(app.UIAxes_2, 'DSB-SC Signal')
            xlabel(app.UIAxes_2, 'Time--->')
            ylabel(app.UIAxes_2, 'Amplitude--->')
            app.FrequencyDomainTab_6 = uitab(app.TabGroup2_6);
            app.FrequencyDomainTab_6.Title = 'Frequency Domain';
            app.UIAxes2_3 = uiaxes(app.FrequencyDomainTab_6);
            title(app.UIAxes2_3, 'DSB-SC Signal')
            xlabel(app.UIAxes2_3, 'Time--->')
            ylabel(app.UIAxes2_3, 'DFT Values-->')
            app.LPFTab = uitab(app.TabGroup_2);
            app.LPFTab.Title = 'LPF';
            app.UIAxes4 = uiaxes(app.LPFTab);
            title(app.UIAxes4, 'Low Pass Filter')
            xlabel(app.UIAxes4, 'Frequency-->')
            ylabel(app.UIAxes4, 'DFT Values')
            app.UIAxes4.YLim = [0 2];
            app.HPFTab = uitab(app.TabGroup_2);
            app.HPFTab.Title = 'HPF';
            app.UIAxes5 = uiaxes(app.HPFTab);
            title(app.UIAxes5, 'High Pass Filter')
            xlabel(app.UIAxes5, 'frequency-->')
            ylabel(app.UIAxes5, 'DFT Values-->')
            app.UIAxes5.YLim = [0 2];
            app.LVSBSignalTab = uitab(app.TabGroup_2);
            app.LVSBSignalTab.Title = 'LVSB Signal';
            app.TabGroup4 = uitabgroup(app.LVSBSignalTab);
            app.TimedomainTab = uitab(app.TabGroup4);
            app.TimedomainTab.Title = 'Time domain';
            app.UIAxes6 = uiaxes(app.TimedomainTab);
            title(app.UIAxes6, 'LVSB Signal')
            xlabel(app.UIAxes6, 'Time--->')
            ylabel(app.UIAxes6, 'Amplitude--->')
            app.FrequnecyDomainTab = uitab(app.TabGroup4);
            app.FrequnecyDomainTab.Title = 'Frequnecy Domain';
            app.UIAxes7 = uiaxes(app.FrequnecyDomainTab);
            title(app.UIAxes7, 'LVSB Signal')
            xlabel(app.UIAxes7, 'time-->')
            ylabel(app.UIAxes7, 'DFT Values-->')
            app.UVSBSignalTab = uitab(app.TabGroup_2);
            app.UVSBSignalTab.Title = 'UVSB Signal';
            app.TabGroup4_2 = uitabgroup(app.UVSBSignalTab);
            app.TimedomainTab_2 = uitab(app.TabGroup4_2);
            app.TimedomainTab_2.Title = 'Time domain';
            app.UIAxes6_2 = uiaxes(app.TimedomainTab_2);
            title(app.UIAxes6_2, 'UVSB Signal')
            xlabel(app.UIAxes6_2, 'Time--->')
            ylabel(app.UIAxes6_2, 'Amplitude--->')
            app.FrequnecyDomainTab_2 = uitab(app.TabGroup4_2);
            app.FrequnecyDomainTab_2.Title = 'Frequnecy Domain';
            app.UIAxes7_2 = uiaxes(app.FrequnecyDomainTab_2);
            title(app.UIAxes7_2, 'UVSB Signal')
            xlabel(app.UIAxes7_2, 'time-->')
            ylabel(app.UIAxes7_2, 'DFT Values-->')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VSB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end