classdef amtech < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image                      matlab.ui.control.Image
        BasicButton                matlab.ui.control.Button
        GenralAMButton             matlab.ui.control.Button
        EnvelopeDetectorButton     matlab.ui.control.Button
        DSBSCButton                matlab.ui.control.Button
        CoherentDetectorButton     matlab.ui.control.Button
        NonCoherentDetectorButton  matlab.ui.control.Button
        CostasLoopButton           matlab.ui.control.Button
        QAMButton                  matlab.ui.control.Button
        SSBButton                  matlab.ui.control.Button
        PhaseShiftingMethodButton  matlab.ui.control.Button
        VSBButton                  matlab.ui.control.Button
        BackToMainButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DSBSCButton
        function DSBSCButtonPushed(app, event)
            dsb_sc
            app.DSBSCButton.BackgroundColor="white";
        end

        % Button pushed function: GenralAMButton
        function am_call(app, event)
            app2
            app.GenralAMButton.BackgroundColor="white";
        end

        % Button pushed function: EnvelopeDetectorButton
        function call_envelope(app, event)
            Envelope_detector
            app.EnvelopeDetectorButton.BackgroundColor="white";
        end

        % Button pushed function: CoherentDetectorButton
        function call_coherent(app, event)
            Coherent_detector
            app.CoherentDetectorButton.BackgroundColor="white";
        end

        % Button pushed function: SSBButton
        function call_ssb(app, event)
            SSB_mod
            app.SSBButton.BackgroundColor="white";
        end

        % Button pushed function: BackToMainButton
        function call_back(app, event)

            Start
            app.BackToMainButton.BackgroundColor="white";
        end

        % Button pushed function: NonCoherentDetectorButton
        function call_noncoherent(app, event)
            Non_coherent
            app.NonCoherentDetectorButton.BackgroundColor="white";
        end

        % Button pushed function: CostasLoopButton
        function call_costas(app, event)
            Costas_loop
            app.CostasLoopButton.BackgroundColor="white";
        end

        % Button pushed function: QAMButton
        function call_QAM(app, event)
            QAM
            app.QAMButton.BackgroundColor="white";
        end

        % Button pushed function: PhaseShiftingMethodButton
        function call_PSM(app, event)
            PhaseShiftingMethod
            app.PhaseShiftingMethodButton.BackgroundColor="white";
        end

        % Button pushed function: VSBButton
        function call_VSB(app, event)
            VSB
            app.VSBButton.BackgroundColor="white";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '2.png';
            app.BasicButton = uibutton(app.UIFigure, 'push');
            app.BasicButton.Text = 'Basic';
            app.GenralAMButton = uibutton(app.UIFigure, 'push');
            app.GenralAMButton.ButtonPushedFcn = createCallbackFcn(app, @am_call, true);
            app.GenralAMButton.Text = 'Genral AM';
            app.EnvelopeDetectorButton = uibutton(app.UIFigure, 'push');
            app.EnvelopeDetectorButton.ButtonPushedFcn = createCallbackFcn(app, @call_envelope, true);
            app.EnvelopeDetectorButton.Text = 'Envelope Detector';
            app.DSBSCButton = uibutton(app.UIFigure, 'push');
            app.DSBSCButton.ButtonPushedFcn = createCallbackFcn(app, @DSBSCButtonPushed, true);
            app.DSBSCButton.Text = 'DSB-SC';
            app.CoherentDetectorButton = uibutton(app.UIFigure, 'push');
            app.CoherentDetectorButton.ButtonPushedFcn = createCallbackFcn(app, @call_coherent, true);
            app.CoherentDetectorButton.Text = 'Coherent Detector';
            app.NonCoherentDetectorButton = uibutton(app.UIFigure, 'push');
            app.NonCoherentDetectorButton.ButtonPushedFcn = createCallbackFcn(app, @call_noncoherent, true);
            app.NonCoherentDetectorButton.Text = 'Non-Coherent Detector';
            app.CostasLoopButton = uibutton(app.UIFigure, 'push');
            app.CostasLoopButton.ButtonPushedFcn = createCallbackFcn(app, @call_costas, true);
            app.CostasLoopButton.Text = 'Costas Loop';
            app.QAMButton = uibutton(app.UIFigure, 'push');
            app.QAMButton.ButtonPushedFcn = createCallbackFcn(app, @call_QAM, true);
            app.QAMButton.Text = 'QAM';
            app.SSBButton = uibutton(app.UIFigure, 'push');
            app.SSBButton.ButtonPushedFcn = createCallbackFcn(app, @call_ssb, true);
            app.SSBButton.Text = 'SSB';
            app.PhaseShiftingMethodButton = uibutton(app.UIFigure, 'push');
            app.PhaseShiftingMethodButton.ButtonPushedFcn = createCallbackFcn(app, @call_PSM, true);
            app.PhaseShiftingMethodButton.Text = 'Phase Shifting Method';
            app.VSBButton = uibutton(app.UIFigure, 'push');
            app.VSBButton.ButtonPushedFcn = createCallbackFcn(app, @call_VSB, true);
            app.VSBButton.Text = 'VSB';
            app.BackToMainButton = uibutton(app.UIFigure, 'push');
            app.BackToMainButton.ButtonPushedFcn = createCallbackFcn(app, @call_back, true);
            app.BackToMainButton.Text = 'Back To Main';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = amtech

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end