classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Label_4                   matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        Label_6                   matlab.ui.control.Label
        Label_5                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        TextArea_4                matlab.ui.control.TextArea
        Label_8                   matlab.ui.control.Label
        Label_9                   matlab.ui.control.Label
        Label_10                  matlab.ui.control.Label
        Label_11                  matlab.ui.control.Label
        Label_12                  matlab.ui.control.Label
        aaaaLabel                 matlab.ui.control.Label
        aaaaLabel_2               matlab.ui.control.Label
        TabGroup4                 matlab.ui.container.TabGroup
        TheoryTab                 matlab.ui.container.Tab
        TabGroup5                 matlab.ui.container.TabGroup
        TheoryTab_2               matlab.ui.container.Tab
        TextArea_5                matlab.ui.control.TextArea
        ConditionsTab             matlab.ui.container.Tab
        Image5                    matlab.ui.control.Image
        Image6                    matlab.ui.control.Image
        Image8                    matlab.ui.control.Image
        FrequencyDomainTab_4      matlab.ui.container.Tab
        Image7                    matlab.ui.control.Image
        Image9                    matlab.ui.control.Image
        Image10                   matlab.ui.control.Image
        ObservationsTab           matlab.ui.container.Tab
        TextArea_6                matlab.ui.control.TextArea
        SingleToneModulationTab   matlab.ui.container.Tab
        Image11                   matlab.ui.control.Image
        Image12                   matlab.ui.control.Image
        Image13                   matlab.ui.control.Image
        SimulationTab             matlab.ui.container.Tab
        TabGroup                  matlab.ui.container.TabGroup
        MessagesignalTab          matlab.ui.container.Tab
        AmplitudeVSlider_2Label   matlab.ui.control.Label
        AmplitudeVSlider_2        matlab.ui.control.Slider
        FrequencyHzSlider_2Label  matlab.ui.control.Label
        FrequencyHzSlider_2       matlab.ui.control.Slider
        TabGroup2                 matlab.ui.container.TabGroup
        TimeDomainTab             matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        FrequencyDomainTab        matlab.ui.container.Tab
        UIAxes_2                  matlab.ui.control.UIAxes
        CarriersignalTab          matlab.ui.container.Tab
        TabGroup2_2               matlab.ui.container.TabGroup
        TimeDomainTab_2           matlab.ui.container.Tab
        UIAxes2                   matlab.ui.control.UIAxes
        FrequencyDomainTab_2      matlab.ui.container.Tab
        UIAxes2_2                 matlab.ui.control.UIAxes
        AmplitudeVSliderLabel     matlab.ui.control.Label
        AmplitudeVSlider          matlab.ui.control.Slider
        FrequencyHzSliderLabel    matlab.ui.control.Label
        FrequencyHzSlider         matlab.ui.control.Slider
        ModulatingSignalTab       matlab.ui.container.Tab
        TabGroup2_3               matlab.ui.container.TabGroup
        TimeDomainTab_3           matlab.ui.container.Tab
        UIAxes3                   matlab.ui.control.UIAxes
        FrequencyDomainTab_3      matlab.ui.container.Tab
        UIAxes3_2                 matlab.ui.control.UIAxes
        MessageSignalButton       matlab.ui.control.Button
        CarrierSignalButton       matlab.ui.control.Button
        AMWaveButton              matlab.ui.control.Button
        Label_13                  matlab.ui.control.Label
        XLabel                    matlab.ui.control.Label
        Image4                    matlab.ui.control.Image
        Image4_2                  matlab.ui.control.Image
        Image4_3                  matlab.ui.control.Image
        Image4_4                  matlab.ui.control.Image
        XLabel_2                  matlab.ui.control.Label
        KaEditField               matlab.ui.control.NumericEditField
        KaEditFieldLabel          matlab.ui.control.Label
        Image3_2                  matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function slider2(app, event)

            
        end

        % Button pushed function: MessageSignalButton
        function plot_button(app, event)
            v_m = app.AmplitudeVSlider_2.Value;
            f_m=app.FrequencyHzSlider_2.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            plot(app.UIAxes,t,mt);
            N=length(mt);
            M=fftshift(fft(mt,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes_2,f,abs(M/fs));
            app.MessageSignalButton.BackgroundColor=[0.9686 0.8549 0.8549];
        end

        % Button pushed function: CarrierSignalButton
        function plot_carrier(app, event)
            v_c = app.AmplitudeVSlider.Value;
            f_c=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            ct=v_c*cos(2*pi*f_c*t);
            plot(app.UIAxes2,t,ct);
             N=length(ct);
            M=fftshift(fft(ct,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes2_2,f,abs(M/fs));
            app.CarrierSignalButton.BackgroundColor=[0.7294 0.7216 0.9608];
        end

        % Button pushed function: AMWaveButton
        function AM_time(app, event)
            v_m = app.AmplitudeVSlider_2.Value;
            f_m=app.FrequencyHzSlider_2.Value;
            t=0:1/1000:1;
            mt=v_m*cos(2*pi*f_m*t);
            v_c = app.AmplitudeVSlider.Value;
            f_c=app.FrequencyHzSlider.Value;
            fs=1000;
            t=0:1/1000:1;
            ct=v_c*cos(2*pi*f_c*t);
            ka=app.KaEditField.Value;
            am_mod=(1+ka*mt).*ct;
            plot(app.UIAxes3,t,am_mod);
            N=length(am_mod);
            M=fftshift(fft(am_mod,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes3_2,f,abs(M/fs));
            app.AMWaveButton.BackgroundColor=[0.8196 0.9216 0.7725];
        end

        % Value changed function: KaEditField
        function ka(app, event)
           
            app.KaEditField.BackgroundColor="cyan";
        end

        % Value changed function: AmplitudeVSlider_2
        function slider1(app, event)
            value = app.AmplitudeVSlider_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Value = {'Conventional AM'};
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_12 = uilabel(app.UIFigure);
            app.aaaaLabel = uilabel(app.UIFigure);
            app.aaaaLabel.Text = {'a'; 'a'; 'a'; 'a'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; ''; '.'; ''; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '..'; '.'; '.'; ''; '..'; ''; '.'; '.'; ''; '.'; '.'; '..'; ''; '.'; '..'; ''; '.'; ''; ''; ''; ''; ''; ''};
            app.aaaaLabel_2 = uilabel(app.UIFigure);
            app.aaaaLabel_2.Text = {'a'; 'a'; 'a'; 'a'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; ''; '.'; ''; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '..'; '.'; '.'; ''; '..'; ''; '.'; '.'; ''; '.'; '.'; '..'; ''; '.'; '..'; ''; '.'; ''; ''; ''; ''; ''; ''};
            app.TabGroup4 = uitabgroup(app.UIFigure);
            app.TheoryTab = uitab(app.TabGroup4);
            app.TheoryTab.Title = 'Theory';
            app.TabGroup5 = uitabgroup(app.TheoryTab);
            app.TheoryTab_2 = uitab(app.TabGroup5);
            app.TheoryTab_2.Title = 'Theory';
            app.TextArea_5 = uitextarea(app.TheoryTab_2);
            app.TextArea_5.Value = {''; 'Consider a sinusoidal carrier wave defined by '; 'c(t)  = AcCos(2*pi*fc*t)  '; 'where is the carrier amplitude and is the carrier frequency. The information-bearing signal or message signal is denoted by m(t); the terms “information-bearing signal” and “message signal” are used interchangeably throughout the book. To simplify the exposition without affecting the results obtained and conclusions reached, we have assumed that the phase of the carrier wave is zero. Amplitude modulation (AM) is formally defined as a process in which the amplitude of the carrier wave is varied about a mean value, linearly with the message signal An amplitude-modulated (AM) wave may thus be described as a function of time as follows:'; 's(t) = Ac[1 + ka*m(t)]cos(2*pi*fc*t)'; 'where there is a constant called the amplitude sensitivity of the modulator responsible for the generation of the modulated signal Typically, the carrier amplitude and the message signal are measured in volts, in which case the amplitude sensitivity(ka) is measured in 1/Volt.'; ''};
            app.ConditionsTab = uitab(app.TabGroup5);
            app.ConditionsTab.Title = 'Conditions';
            app.Image5 = uiimage(app.ConditionsTab);
            app.Image5.ImageSource = 'pasted image 0 (1).png';
            app.Image6 = uiimage(app.ConditionsTab);
            app.Image6.ImageSource = 'pasted image 0.png';
            app.Image8 = uiimage(app.ConditionsTab);
            app.Image8.ImageSource = '0.png';
            app.FrequencyDomainTab_4 = uitab(app.TabGroup5);
            app.FrequencyDomainTab_4.Title = 'Frequency Domain';
            app.Image7 = uiimage(app.FrequencyDomainTab_4);
            app.Image7.ImageSource = 'pasted image 0 (2).png';
            app.Image9 = uiimage(app.FrequencyDomainTab_4);
            app.Image9.ImageSource = 'xyz.png';
            app.Image10 = uiimage(app.FrequencyDomainTab_4);
            app.Image10.ImageSource = 'xyz1.png';
            app.ObservationsTab = uitab(app.TabGroup5);
            app.ObservationsTab.Title = 'Observations';
            app.TextArea_6 = uitextarea(app.ObservationsTab);
            app.TextArea_6.Value = {''; 'From the spectrum of s(t), we make three important observations:'; '1. As a result of the modulation process, the spectrum of the message signal for negative frequencies extending from to 0 becomes completely visible for positive (i.e., measurable) frequencies, provided that the carrier frequency satisfies the condition herein lies the importance of the idea of “negative” frequencies.'; '2. For positive frequencies, the portion of the spectrum of an AM wave lying above the carrier frequency is referred to as the upper sideband, whereas the symmetric portion below is referred to as the lower sideband. The condition ensures that the sidebands do not overlap. Moreover, with the upper sideband, lower sideband, and carrier fully represented in the spectrum of s(t).'; '3. For positive frequencies, the highest frequency component of the AM wave equals and the lowest frequency component equals The difference between these two frequencies defines the transmission bandwidth of the AM wave, which is exactly twice the message bandwidth W; that is					B = 2W'};
            app.SingleToneModulationTab = uitab(app.TabGroup5);
            app.SingleToneModulationTab.Title = 'Single Tone Modulation';
            app.Image11 = uiimage(app.SingleToneModulationTab);
            app.Image11.ImageSource = 'pasted image 0 (3).png';
            app.Image12 = uiimage(app.SingleToneModulationTab);
            app.Image12.ImageSource = 'pasted image 0 (4).png';
            app.Image13 = uiimage(app.SingleToneModulationTab);
            app.Image13.ImageSource = 'pasted image 0 (5).png';
            app.SimulationTab = uitab(app.TabGroup4);
            app.SimulationTab.Title = 'Simulation';
            app.TabGroup = uitabgroup(app.SimulationTab);
            app.MessagesignalTab = uitab(app.TabGroup);
            app.MessagesignalTab.Title = 'Message signal';
            app.AmplitudeVSlider_2Label = uilabel(app.MessagesignalTab);
            app.AmplitudeVSlider_2Label.Text = 'Amplitude (V)';
            app.AmplitudeVSlider_2 = uislider(app.MessagesignalTab);
            app.AmplitudeVSlider_2.Limits = [0 2];
            app.AmplitudeVSlider_2.ValueChangedFcn = createCallbackFcn(app, @slider1, true);
            app.FrequencyHzSlider_2Label = uilabel(app.MessagesignalTab);
            app.FrequencyHzSlider_2Label.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider_2 = uislider(app.MessagesignalTab);
            app.FrequencyHzSlider_2.Limits = [0 5];
            app.TabGroup2 = uitabgroup(app.MessagesignalTab);
            app.TimeDomainTab = uitab(app.TabGroup2);
            app.TimeDomainTab.Title = 'Time Domain';
            app.UIAxes = uiaxes(app.TimeDomainTab);
            title(app.UIAxes, 'Message signal')
            xlabel(app.UIAxes, 'Time--->')
            ylabel(app.UIAxes, 'Amplitude-->')
            app.FrequencyDomainTab = uitab(app.TabGroup2);
            app.FrequencyDomainTab.Title = 'Frequency Domain';
            app.UIAxes_2 = uiaxes(app.FrequencyDomainTab);
            title(app.UIAxes_2, 'Message signal')
            xlabel(app.UIAxes_2, 'Frequency--->')
            ylabel(app.UIAxes_2, 'DFT Values--->')
            app.CarriersignalTab = uitab(app.TabGroup);
            app.CarriersignalTab.Title = 'Carrier signal';
            app.TabGroup2_2 = uitabgroup(app.CarriersignalTab);
            app.TimeDomainTab_2 = uitab(app.TabGroup2_2);
            app.TimeDomainTab_2.Title = 'Time Domain';
            app.UIAxes2 = uiaxes(app.TimeDomainTab_2);
            title(app.UIAxes2, 'Carrier Signal')
            xlabel(app.UIAxes2, 'Time--->')
            ylabel(app.UIAxes2, 'Amplitude-->')
            app.FrequencyDomainTab_2 = uitab(app.TabGroup2_2);
            app.FrequencyDomainTab_2.Title = 'Frequency Domain';
            app.UIAxes2_2 = uiaxes(app.FrequencyDomainTab_2);
            title(app.UIAxes2_2, 'Carrier Signal')
            xlabel(app.UIAxes2_2, 'Frequency--->')
            ylabel(app.UIAxes2_2, 'DFT Values-->')
            app.AmplitudeVSliderLabel = uilabel(app.CarriersignalTab);
            app.AmplitudeVSliderLabel.Text = 'Amplitude (V)';
            app.AmplitudeVSlider = uislider(app.CarriersignalTab);
            app.AmplitudeVSlider.Limits = [0 4];
            app.FrequencyHzSliderLabel = uilabel(app.CarriersignalTab);
            app.FrequencyHzSliderLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider = uislider(app.CarriersignalTab);
            app.FrequencyHzSlider.Limits = [0 60];
            app.ModulatingSignalTab = uitab(app.TabGroup);
            app.ModulatingSignalTab.Title = 'Modulating Signal';
            app.TabGroup2_3 = uitabgroup(app.ModulatingSignalTab);
            app.TimeDomainTab_3 = uitab(app.TabGroup2_3);
            app.TimeDomainTab_3.Title = 'Time Domain';
            app.UIAxes3 = uiaxes(app.TimeDomainTab_3);
            title(app.UIAxes3, 'AM Signal')
            xlabel(app.UIAxes3, 'Time--->')
            ylabel(app.UIAxes3, 'Amplitude-->')
            app.FrequencyDomainTab_3 = uitab(app.TabGroup2_3);
            app.FrequencyDomainTab_3.Title = 'Frequency Domain';
            app.UIAxes3_2 = uiaxes(app.FrequencyDomainTab_3);
            title(app.UIAxes3_2, 'AM Signal')
            xlabel(app.UIAxes3_2, 'Frequency--->')
            ylabel(app.UIAxes3_2, 'DFT Values-->')
            app.MessageSignalButton = uibutton(app.SimulationTab, 'push');
            app.MessageSignalButton.ButtonPushedFcn = createCallbackFcn(app, @plot_button, true);
            app.MessageSignalButton.Text = 'Message Signal';
            app.CarrierSignalButton = uibutton(app.SimulationTab, 'push');
            app.CarrierSignalButton.ButtonPushedFcn = createCallbackFcn(app, @plot_carrier, true);
            app.CarrierSignalButton.Text = {'Carrier Signal'; ''};
            app.AMWaveButton = uibutton(app.SimulationTab, 'push');
            app.AMWaveButton.ButtonPushedFcn = createCallbackFcn(app, @AM_time, true);
            app.AMWaveButton.Text = 'AM Wave';
            app.Label_13 = uilabel(app.SimulationTab);
            app.Label_13.Text = {'+'; ''; '1'};
            app.XLabel = uilabel(app.SimulationTab);
            app.XLabel.Text = 'X';
            app.Image4 = uiimage(app.SimulationTab);
            app.Image4.ImageSource = 'arrow.png';
            app.Image4_2 = uiimage(app.SimulationTab);
            app.Image4_2.ImageSource = 'arrow.png';
            app.Image4_3 = uiimage(app.SimulationTab);
            app.Image4_3.ImageSource = 'arrow.png';
            app.Image4_4 = uiimage(app.SimulationTab);
            app.Image4_4.ImageSource = 'arrow.png';
            app.XLabel_2 = uilabel(app.SimulationTab);
            app.XLabel_2.Text = 'X';
            app.KaEditField = uieditfield(app.SimulationTab, 'numeric');
            app.KaEditField.ValueChangedFcn = createCallbackFcn(app, @ka, true);
            app.KaEditFieldLabel = uilabel(app.SimulationTab);
            app.KaEditFieldLabel.Text = 'Ka';
            app.Image3_2 = uiimage(app.SimulationTab);
            app.Image3_2.ImageSource = 'DTU,_Delhi_official_logo.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end