classdef dsb_sc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TextArea                  matlab.ui.control.TextArea
        Image                     matlab.ui.control.Image
        aaaaLabel                 matlab.ui.control.Label
        aaaaLabel_2               matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        TheoryTab                 matlab.ui.container.Tab
        Image3                    matlab.ui.control.Image
        Image4                    matlab.ui.control.Image
        Image5                    matlab.ui.control.Image
        Image6                    matlab.ui.control.Image
        SimulationTab             matlab.ui.container.Tab
        XLabel                    matlab.ui.control.Label
        TabGroup2                 matlab.ui.container.TabGroup
        MessageTab                matlab.ui.container.Tab
        TabGroup_2                matlab.ui.container.TabGroup
        TimeDomainTab             matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        FourierDomainTab          matlab.ui.container.Tab
        UIAxes_2                  matlab.ui.control.UIAxes
        AmplitudeVSliderLabel     matlab.ui.control.Label
        AmplitudeVSlider          matlab.ui.control.Slider
        FrequencyHzSliderLabel    matlab.ui.control.Label
        FrequencyHzSlider         matlab.ui.control.Slider
        CarrierTab                matlab.ui.container.Tab
        TabGroup_3                matlab.ui.container.TabGroup
        TimeDomainTab_2           matlab.ui.container.Tab
        UIAxes2                   matlab.ui.control.UIAxes
        FourierDomainTab_2        matlab.ui.container.Tab
        UIAxes2_2                 matlab.ui.control.UIAxes
        FrequencyHzSlider_2Label  matlab.ui.control.Label
        FrequencyHzSlider_2       matlab.ui.control.Slider
        AmplitudeVSlider_2Label   matlab.ui.control.Label
        AmplitudeVSlider_2        matlab.ui.control.Slider
        ModulatingSignalTab       matlab.ui.container.Tab
        TabGroup_4                matlab.ui.container.TabGroup
        TimeDomainTab_3           matlab.ui.container.Tab
        UIAxes3                   matlab.ui.control.UIAxes
        FourierDomainTab_3        matlab.ui.container.Tab
        UIAxes3_2                 matlab.ui.control.UIAxes
        mtmessagesignalButton     matlab.ui.control.Button
        CtCarrierSignalButton     matlab.ui.control.Button
        mtxctDSBSCSignalButton    matlab.ui.control.Button
        Image2                    matlab.ui.control.Image
        Image2_2                  matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: mtmessagesignalButton
        function plot_message(app, event)
             v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
            plot(app.UIAxes,t,mt);
            N=length(mt);
            M=fftshift(fft(mt,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes_2,f,abs(M/fs));
            app.mtmessagesignalButton.BackgroundColor=[0.8706 0.7647 0.7647];
        end

        % Button pushed function: CtCarrierSignalButton
        function plot_carrier(app, event)
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzSlider_2.Value;
            t=0:1/1000:1;
            fs=1000;
            ct=v_c*cos(2*pi*f_c*t);
            plot(app.UIAxes2,t,ct);
             N=length(ct);
            M=fftshift(fft(ct,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes2_2,f,abs(M/fs));
            app.CtCarrierSignalButton.BackgroundColor =[0.702 0.7886 0.9098];
        end

        % Button pushed function: mtxctDSBSCSignalButton
        function plot_dsbsc(app, event)
            v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/1000:1;
            fs=1000;
            mt=v_m*cos(2*pi*f_m*t);
             v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzSlider_2.Value;
            t=0:1/1000:1;
            ct=v_c*cos(2*pi*f_c*t);
            dsb=mt.*ct;
            plot(app.UIAxes3,t,dsb);
            N=length(dsb);
            M=fftshift(fft(dsb,N));
            f=fs*(-N/2:N/2-1)/N;
            plot(app.UIAxes3_2,f,abs(M/fs));
            app.mtxctDSBSCSignalButton.BackgroundColor=[0.7686 0.9098 0.702];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'DSB-SC Modulation'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'DTU,_Delhi_official_logo.png';
            app.aaaaLabel = uilabel(app.UIFigure);
            app.aaaaLabel.Text = {'a'; 'a'; 'a'; 'a'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; ''; '.'; ''; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '..'; '.'; '.'; ''; '..'; ''; '.'; '.'; ''; '.'; '.'; '..'; ''; '.'; '..'; ''; '.'; ''; ''; ''; ''; ''; ''};
            app.aaaaLabel_2 = uilabel(app.UIFigure);
            app.aaaaLabel_2.Text = {'a'; 'a'; 'a'; 'a'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '.'; ''; '.'; ''; '.'; '.'; '.'; '.'; '.'; '.'; '.'; '..'; '.'; '.'; ''; '..'; ''; '.'; '.'; ''; '.'; '.'; '..'; ''; '.'; '..'; ''; '.'; ''; ''; ''; ''; ''; ''};
            app.Label = uilabel(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TheoryTab = uitab(app.TabGroup);
            app.TheoryTab.Title = 'Theory';
            app.Image3 = uiimage(app.TheoryTab);
            app.Image3.ImageSource = 'dsb1.png';
            app.Image4 = uiimage(app.TheoryTab);
            app.Image4.ImageSource = 'dsb2.png';
            app.Image5 = uiimage(app.TheoryTab);
            app.Image5.ImageSource = 'dsb3.png';
            app.Image6 = uiimage(app.TheoryTab);
            app.Image6.ImageSource = 'dsb4.png';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.XLabel = uilabel(app.SimulationTab);
            app.XLabel.Text = 'X';
            app.TabGroup2 = uitabgroup(app.SimulationTab);
            app.MessageTab = uitab(app.TabGroup2);
            app.MessageTab.Title = 'Message';
            app.TabGroup_2 = uitabgroup(app.MessageTab);
            app.TimeDomainTab = uitab(app.TabGroup_2);
            app.TimeDomainTab.Title = 'Time Domain';
            app.UIAxes = uiaxes(app.TimeDomainTab);
            title(app.UIAxes, 'Message Signal')
            xlabel(app.UIAxes, 'time-->')
            ylabel(app.UIAxes, 'Amplitude--->')
            app.FourierDomainTab = uitab(app.TabGroup_2);
            app.FourierDomainTab.Title = 'Fourier Domain';
            app.UIAxes_2 = uiaxes(app.FourierDomainTab);
            title(app.UIAxes_2, 'Message Signal')
            xlabel(app.UIAxes_2, 'Frequency--->')
            ylabel(app.UIAxes_2, 'DFT Values--->')
            app.AmplitudeVSliderLabel = uilabel(app.MessageTab);
            app.AmplitudeVSliderLabel.Text = 'Amplitude (V)';
            app.AmplitudeVSlider = uislider(app.MessageTab);
            app.AmplitudeVSlider.Limits = [0 2];
            app.FrequencyHzSliderLabel = uilabel(app.MessageTab);
            app.FrequencyHzSliderLabel.Text = {'Frequency (Hz)'; ''};
            app.FrequencyHzSlider = uislider(app.MessageTab);
            app.FrequencyHzSlider.Limits = [0 5];
            app.CarrierTab = uitab(app.TabGroup2);
            app.CarrierTab.Title = 'Carrier';
            app.TabGroup_3 = uitabgroup(app.CarrierTab);
            app.TimeDomainTab_2 = uitab(app.TabGroup_3);
            app.TimeDomainTab_2.Title = 'Time Domain';
            app.UIAxes2 = uiaxes(app.TimeDomainTab_2);
            title(app.UIAxes2, 'Carrier Signal')
            xlabel(app.UIAxes2, 'time--->')
            ylabel(app.UIAxes2, 'Amplitude-->')
            app.FourierDomainTab_2 = uitab(app.TabGroup_3);
            app.FourierDomainTab_2.Title = 'Fourier Domain';
            app.UIAxes2_2 = uiaxes(app.FourierDomainTab_2);
            title(app.UIAxes2_2, 'Carrier Signal')
            xlabel(app.UIAxes2_2, 'frequency--->')
            ylabel(app.UIAxes2_2, 'DFT Values-->')
            app.FrequencyHzSlider_2Label = uilabel(app.CarrierTab);
            app.FrequencyHzSlider_2Label.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider_2 = uislider(app.CarrierTab);
            app.FrequencyHzSlider_2.Limits = [0 50];
            app.AmplitudeVSlider_2Label = uilabel(app.CarrierTab);
            app.AmplitudeVSlider_2Label.Text = 'Amplitude (V)';
            app.AmplitudeVSlider_2 = uislider(app.CarrierTab);
            app.AmplitudeVSlider_2.Limits = [0 4];
            app.ModulatingSignalTab = uitab(app.TabGroup2);
            app.ModulatingSignalTab.Title = 'Modulating Signal';
            app.TabGroup_4 = uitabgroup(app.ModulatingSignalTab);
            app.TimeDomainTab_3 = uitab(app.TabGroup_4);
            app.TimeDomainTab_3.Title = 'Time Domain';
            app.UIAxes3 = uiaxes(app.TimeDomainTab_3);
            title(app.UIAxes3, 'DSB-SC Signal')
            xlabel(app.UIAxes3, 'time--->')
            ylabel(app.UIAxes3, 'Amplitude--->')
            app.FourierDomainTab_3 = uitab(app.TabGroup_4);
            app.FourierDomainTab_3.Title = 'Fourier Domain';
            app.UIAxes3_2 = uiaxes(app.FourierDomainTab_3);
            title(app.UIAxes3_2, 'DSB-SC Signal')
            xlabel(app.UIAxes3_2, 'Frequency--->')
            ylabel(app.UIAxes3_2, 'DFT Values--->')
            app.mtmessagesignalButton = uibutton(app.SimulationTab, 'push');
            app.mtmessagesignalButton.ButtonPushedFcn = createCallbackFcn(app, @plot_message, true);
            app.mtmessagesignalButton.Text = {'m(t)'; 'message signal'};
            app.CtCarrierSignalButton = uibutton(app.SimulationTab, 'push');
            app.CtCarrierSignalButton.ButtonPushedFcn = createCallbackFcn(app, @plot_carrier, true);
            app.CtCarrierSignalButton.Text = {'C(t)'; 'Carrier Signal '};
            app.mtxctDSBSCSignalButton = uibutton(app.SimulationTab, 'push');
            app.mtxctDSBSCSignalButton.ButtonPushedFcn = createCallbackFcn(app, @plot_dsbsc, true);
            app.mtxctDSBSCSignalButton.Text = {'m(t) x c(t)'; 'DSB-SC Signal'; ''};
            app.Image2 = uiimage(app.SimulationTab);
            app.Image2.ImageSource = 'arrow.png';
            app.Image2_2 = uiimage(app.SimulationTab);
            app.Image2_2.ImageSource = 'arrow.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dsb_sc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end