classdef fm_mod < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        TextArea                  matlab.ui.control.TextArea
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        Image2                    matlab.ui.control.Image
        TextArea_2                matlab.ui.control.TextArea
        AmplitudeVSliderLabel     matlab.ui.control.Label
        AmplitudeVSlider          matlab.ui.control.Slider
        FrequencyHzSliderLabel    matlab.ui.control.Label
        FrequencyHzSlider         matlab.ui.control.Slider
        AmplitudeVSlider_2Label   matlab.ui.control.Label
        AmplitudeVSlider_2        matlab.ui.control.Slider
        FrequencyHzSlider_2Label  matlab.ui.control.Label
        FrequencyHzSlider_2       matlab.ui.control.Slider
        PlotButton                matlab.ui.control.Button
        PlotButton_2              matlab.ui.control.Button
        BetaSliderLabel           matlab.ui.control.Label
        BetaSlider                matlab.ui.control.Slider
        PlotFMSignalButton        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function plot_msg(app, event)
             v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/10000:1;
            mt=v_m*cos(2*pi*f_m*t);
            plot(app.UIAxes,t,mt);
        end

        % Button pushed function: PlotButton_2
        function plot_c(app, event)
             v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzSlider_2.Value;
            t=0:1/10000:1;
            ct=v_c*cos(2*pi*f_c*t);
            plot(app.UIAxes2,t,ct);
        end

        % Button pushed function: PlotFMSignalButton
        function plot_fm(app, event)

            f_m=app.FrequencyHzSlider.Value;
            t=0:1/10000:1;
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzSlider_2.Value;
            beta=app.BetaSlider.Value;
            thetan=2*pi*f_c*t+beta*sin(2*pi*f_m*t);
            fm=v_c*cos(thetan);
             plot(app.UIAxes3,t,fm);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'fm.png';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Ac*Cos(2*pi*fc*t+beta*Sin(2*pi*fm*t)'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Message Signal'; ''})
            xlabel(app.UIAxes, 'time--->')
            ylabel(app.UIAxes, 'Amplitude--->')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Carrer Signal')
            xlabel(app.UIAxes2, 'time--->')
            ylabel(app.UIAxes2, 'Amplitude--->')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, {'FM Signal'; ''})
            xlabel(app.UIAxes3, 'time--->')
            ylabel(app.UIAxes3, 'Amplitude-->')
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'DTU,_Delhi_official_logo.png';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Frequency Modulation'};
            app.AmplitudeVSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeVSliderLabel.Text = 'Amplitude (V)';
            app.AmplitudeVSlider = uislider(app.UIFigure);
            app.AmplitudeVSlider.Limits = [0 2];
            app.FrequencyHzSliderLabel = uilabel(app.UIFigure);
            app.FrequencyHzSliderLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider = uislider(app.UIFigure);
            app.FrequencyHzSlider.Limits = [0 20];
            app.AmplitudeVSlider_2Label = uilabel(app.UIFigure);
            app.AmplitudeVSlider_2Label.Text = 'Amplitude (V)';
            app.AmplitudeVSlider_2 = uislider(app.UIFigure);
            app.AmplitudeVSlider_2.Limits = [0 4];
            app.FrequencyHzSlider_2Label = uilabel(app.UIFigure);
            app.FrequencyHzSlider_2Label.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider_2 = uislider(app.UIFigure);
            app.FrequencyHzSlider_2.Limits = [0 50];
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @plot_msg, true);
            app.PlotButton.Text = 'Plot';
            app.PlotButton_2 = uibutton(app.UIFigure, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @plot_c, true);
            app.PlotButton_2.Text = 'Plot';
            app.BetaSliderLabel = uilabel(app.UIFigure);
            app.BetaSliderLabel.Text = 'Beta';
            app.BetaSlider = uislider(app.UIFigure);
            app.BetaSlider.Limits = [0 6];
            app.PlotFMSignalButton = uibutton(app.UIFigure, 'push');
            app.PlotFMSignalButton.ButtonPushedFcn = createCallbackFcn(app, @plot_fm, true);
            app.PlotFMSignalButton.Text = 'Plot FM Signal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fm_mod

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end