classdef phase_mod < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image2                    matlab.ui.control.Image
        TextArea_2                matlab.ui.control.TextArea
        PlotButton                matlab.ui.control.Button
        PlotButton_2              matlab.ui.control.Button
        PlotFMSignalButton        matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        MessageSignalTab          matlab.ui.container.Tab
        AmplitudeVSliderLabel     matlab.ui.control.Label
        AmplitudeVSlider          matlab.ui.control.Slider
        FrequencyHzSliderLabel    matlab.ui.control.Label
        FrequencyHzSlider         matlab.ui.control.Slider
        TabGroup2                 matlab.ui.container.TabGroup
        TimeDomainTab             matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        FrequencyDomianTab        matlab.ui.container.Tab
        UIAxes_2                  matlab.ui.control.UIAxes
        CarrierSignalTab          matlab.ui.container.Tab
        FrequencyHzSlider_2Label  matlab.ui.control.Label
        FrequencyHzSlider_2       matlab.ui.control.Slider
        AmplitudeVSlider_2Label   matlab.ui.control.Label
        AmplitudeVSlider_2        matlab.ui.control.Slider
        TabGroup2_2               matlab.ui.container.TabGroup
        TimeDomainTab_2           matlab.ui.container.Tab
        UIAxes2                   matlab.ui.control.UIAxes
        FrequencyDomianTab_2      matlab.ui.container.Tab
        UIAxes2_2                 matlab.ui.control.UIAxes
        ModulatingSignalTab       matlab.ui.container.Tab
        TabGroup2_3               matlab.ui.container.TabGroup
        TimeDomainTab_3           matlab.ui.container.Tab
        UIAxes3                   matlab.ui.control.UIAxes
        FrequencyDomianTab_3      matlab.ui.container.Tab
        UIAxes3_2                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function plot_msg(app, event)
             v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/10000:1;
            mt=v_m*cos(2*pi*f_m*t);
            plot(app.UIAxes,t,mt);
        end

        % Button pushed function: PlotButton_2
        function plot_c(app, event)
             v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzSlider_2.Value;
            t=0:1/10000:1;
            ct=v_c*cos(2*pi*f_c*t);
            plot(app.UIAxes2,t,ct);
        end

        % Button pushed function: PlotFMSignalButton
        function plot_fm(app, event)

             v_m = app.AmplitudeVSlider.Value;
            f_m=app.FrequencyHzSlider.Value;
            t=0:1/10000:1;
            mt=v_m*cos(2*pi*f_m*t);
            v_c = app.AmplitudeVSlider_2.Value;
            f_c=app.FrequencyHzSlider_2.Value;
            theta=2*pi*f_c*t+10*mt;
            pm=v_c*cos(theta);
             plot(app.UIAxes3,t,pm);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'DTU,_Delhi_official_logo.png';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Phase Modulation'};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @plot_msg, true);
            app.PlotButton.Text = 'Plot';
            app.PlotButton_2 = uibutton(app.UIFigure, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @plot_c, true);
            app.PlotButton_2.Text = 'Plot';
            app.PlotFMSignalButton = uibutton(app.UIFigure, 'push');
            app.PlotFMSignalButton.ButtonPushedFcn = createCallbackFcn(app, @plot_fm, true);
            app.PlotFMSignalButton.Text = 'Plot FM Signal';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MessageSignalTab = uitab(app.TabGroup);
            app.MessageSignalTab.Title = 'Message Signal';
            app.AmplitudeVSliderLabel = uilabel(app.MessageSignalTab);
            app.AmplitudeVSliderLabel.Text = 'Amplitude (V)';
            app.AmplitudeVSlider = uislider(app.MessageSignalTab);
            app.AmplitudeVSlider.Limits = [0 2];
            app.FrequencyHzSliderLabel = uilabel(app.MessageSignalTab);
            app.FrequencyHzSliderLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider = uislider(app.MessageSignalTab);
            app.FrequencyHzSlider.Limits = [0 10];
            app.TabGroup2 = uitabgroup(app.MessageSignalTab);
            app.TimeDomainTab = uitab(app.TabGroup2);
            app.TimeDomainTab.Title = 'Time Domain';
            app.UIAxes = uiaxes(app.TimeDomainTab);
            title(app.UIAxes, {'Message Signal'; ''})
            xlabel(app.UIAxes, 'time--->')
            ylabel(app.UIAxes, 'Amplitude--->')
            app.FrequencyDomianTab = uitab(app.TabGroup2);
            app.FrequencyDomianTab.Title = 'Frequency Domian';
            app.UIAxes_2 = uiaxes(app.FrequencyDomianTab);
            title(app.UIAxes_2, {'Message Signal'; ''})
            xlabel(app.UIAxes_2, 'frequency--->')
            ylabel(app.UIAxes_2, 'DFT Values--->')
            app.CarrierSignalTab = uitab(app.TabGroup);
            app.CarrierSignalTab.Title = 'Carrier Signal';
            app.FrequencyHzSlider_2Label = uilabel(app.CarrierSignalTab);
            app.FrequencyHzSlider_2Label.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider_2 = uislider(app.CarrierSignalTab);
            app.FrequencyHzSlider_2.Limits = [0 120];
            app.AmplitudeVSlider_2Label = uilabel(app.CarrierSignalTab);
            app.AmplitudeVSlider_2Label.Text = 'Amplitude (V)';
            app.AmplitudeVSlider_2 = uislider(app.CarrierSignalTab);
            app.AmplitudeVSlider_2.Limits = [0 4];
            app.TabGroup2_2 = uitabgroup(app.CarrierSignalTab);
            app.TimeDomainTab_2 = uitab(app.TabGroup2_2);
            app.TimeDomainTab_2.Title = 'Time Domain';
            app.UIAxes2 = uiaxes(app.TimeDomainTab_2);
            title(app.UIAxes2, 'Carrer Signal')
            xlabel(app.UIAxes2, 'time--->')
            ylabel(app.UIAxes2, 'Amplitude--->')
            app.FrequencyDomianTab_2 = uitab(app.TabGroup2_2);
            app.FrequencyDomianTab_2.Title = 'Frequency Domian';
            app.UIAxes2_2 = uiaxes(app.FrequencyDomianTab_2);
            title(app.UIAxes2_2, 'Carrer Signal')
            xlabel(app.UIAxes2_2, 'Frequency--->')
            ylabel(app.UIAxes2_2, 'DFT Values--->')
            app.ModulatingSignalTab = uitab(app.TabGroup);
            app.ModulatingSignalTab.Title = 'Modulating Signal';
            app.TabGroup2_3 = uitabgroup(app.ModulatingSignalTab);
            app.TimeDomainTab_3 = uitab(app.TabGroup2_3);
            app.TimeDomainTab_3.Title = 'Time Domain';
            app.UIAxes3 = uiaxes(app.TimeDomainTab_3);
            title(app.UIAxes3, {'PM Signal'; ''})
            xlabel(app.UIAxes3, 'time--->')
            ylabel(app.UIAxes3, 'Amplitude-->')
            app.FrequencyDomianTab_3 = uitab(app.TabGroup2_3);
            app.FrequencyDomianTab_3.Title = 'Frequency Domian';
            app.UIAxes3_2 = uiaxes(app.FrequencyDomianTab_3);
            title(app.UIAxes3_2, {'PM Signal'; ''})
            xlabel(app.UIAxes3_2, 'frequency--->')
            ylabel(app.UIAxes3_2, 'DFT Values--->')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = phase_mod

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end